classdef Final_challange1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WButton                       matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
        WaypointXcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointXcoordinateEditField  matlab.ui.control.NumericEditField
        WaypointYcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointYcoordinateEditField  matlab.ui.control.NumericEditField
        PlotButton                    matlab.ui.control.Button
        TimeEditFieldLabel            matlab.ui.control.Label
        TimeEditField                 matlab.ui.control.NumericEditField
        AButton                       matlab.ui.control.StateButton
        BButton                       matlab.ui.control.StateButton
        Way2_xEditFieldLabel          matlab.ui.control.Label
        Way2_xEditField               matlab.ui.control.NumericEditField
        Way2_yEditFieldLabel          matlab.ui.control.Label
        Way2_yEditField               matlab.ui.control.NumericEditField
        Finaldistance1EditFieldLabel  matlab.ui.control.Label
        Finaldistance1EditField       matlab.ui.control.NumericEditField
        Finaldistance2EditFieldLabel  matlab.ui.control.Label
        Finaldistance2EditField       matlab.ui.control.NumericEditField
        ShowCarButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        x_position = 1;            % The starting position of the dot on x axis
        y_position = 300;            % The starting position of the dot on y axis
        x_front = 300;
        y_front = 453;
        
        
        x_mid = 0;
        y_mid = 0;
        
        startx = 17.75;
        starty = 300;
        start_angle = 0;
        
        x_line = 0;
        y_line = 0;
        x_line_2 = 0;
        y_line_2 = 0;
        
        x_box = [0, 600, 600, 0, 0];  % The outside of the arena
        y_box = [0, 0, 600, 600, 0];  % The outside of the arena
        
        x_waypoint_1=0;
        y_waypoint_1=0;
        x_waypoint_2=0;
        y_waypoint_2=0;
        waypoint_1_position=0;
        R=69.35;
        CircleLeft = [0 0];
        CircleRight = [0 0];
        xp1; yp1; xp2; yp2;
        angle_difference=0;
        car_waypoint_angle=0;
        facing_mod = 0;
        facing_c = 0;
        
        crash = 0;          % Initialize crash as 0
        
        input_force = 150;  % Force pressed on trigger
        force = 0;          % accelaration force
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        deadspeed = 0;      % speed of car
        facing = 0;      % Direction the car is facing in rad 0 = x
        
        flag_straight = 0;
        flag_circle = 0;
        forward_flag = 0;
        flag_selection = 0;
        feed_flag = 0;
        flag_stop = 0;
        way_flag = 0;
        
        s1; s2; s3; s4; s5; s6; s7; s8; s9; s10; s11; s12; s13; s14;
        m = 5.6;
        b = 5;
        c = 0.1;
        x;
        h;
        t;
        tt = 0.1;  % Δt = 0.1 so loop period is set to 0.1 seconds
        tt2 = 0.4;  % Δt = 0.1 so loop period is set to 0.1 seconds
        now;
        Loop;
        TDOALoop;
        x_distance; y_distance; distance;
        waypoint = 0;
        %         count = 0;
        
        % new variables
        car_length = 33.5;
        car_outline = 0;
        car_model = 0;
        steering_radius = 999999;
        car_rotation = 0;
        turning_angle = 0;              % car angle in degrees (USE THIS PARAMETER FOR CALCULATIONS ETC)
        dif_angle = 0;
        
        S_left = 0;
        S_right = 0;
        distance_S_left = 9999;
        distance_S_right = 9999;
        distance_circle = 0;
        
        
        car_model_circle;
        front_line;
        back_line;
        circle_r;
        circle_l;
        car_model_plot;
        
        hand;
        hw;
        hs;
        kitt;
        
        estimated_x_position = 1;
        estimated_y_position = 300;
        old_estimated_x_position = 1;
        old_estimated_y_position = 300;
        estimated_speed = 0;
        estimated_facing = 0;
        estimated_position_difference = 0;
        estimated_position_difference_reference = 0;
        estimated_angle_difference = 0;
        estimated_angle_difference_reference = 0;
        tuning_flag = 0;
        
        prev_position = [1 300; 1 300; 1 300; 1 300; 1 300; 1 300; 1 300; 1 300; 1 300; 1 300];
        
        ref = load('reference.mat', 'reference');
        
        G;
        X;
        
        eps = 0.15;
        peak_eps = 0.6;
        Fs = 40000;
        
        h_1; h_2; h_3; h_4; h_5;
        
        y;
        y_out;
        
        peak_1;
        peak_2;
        peak_3;
        peak_4;
        peak_5;
        
        pos;
        
        est_pos_1 = [17.75 300];
        est_pos_2 = [17.75 300];
        
        showcar = 0;
        
        mic_1;
        mic_2;
        mic_3;
        mic_4;
        mic_5;
    end
    
    methods (Access = private)
        
        function TDOA_estimation(app)
            
            %Recording and estimating the 5-channel data
            
            datagen(app);
            channelEstimation(app);
            firstPeak(app);
            
            % Implementation of localization algorithm from Appendix B.1
            
            n_delay_12 = app.peak_1 - app.peak_2;
            t_delay_12 = (1/app.Fs)*(n_delay_12);
            r_12 = t_delay_12*34326;
            if r_12 == 0
                r_12 = 0.0001;
            end
            
            n_delay_13 = app.peak_1 - app.peak_3;
            t_delay_13 = (1/app.Fs)*(n_delay_13);
            r_13 = t_delay_13*34326;
            if r_13 == 0
                r_13 = 0.0001;
            end
            
            n_delay_14 = app.peak_1 - app.peak_4;
            t_delay_14 = (1/app.Fs)*(n_delay_14);
            r_14 = t_delay_14*34326;
            if r_14 == 0
                r_14 = 0.0001;
            end
            
            n_delay_15 = app.peak_1 - app.peak_5;
            t_delay_15 = (1/app.Fs)*(n_delay_15);
            r_15 = t_delay_15*34326;
            if r_15 == 0
                r_15 = 0.0001;
            end
            
            n_delay_23 = app.peak_2 - app.peak_3;
            t_delay_23 = (1/app.Fs)*(n_delay_23);
            r_23 = t_delay_23*34326;
            if r_23 == 0
                r_23 = 0.0001;
            end
            
            n_delay_24 = app.peak_2 - app.peak_4;
            t_delay_24 = (1/app.Fs)*(n_delay_24);
            r_24 = t_delay_24*34326;
            if r_24 == 0
                r_24 = 0.0001;
            end
            
            n_delay_25 = app.peak_2 - app.peak_5;
            t_delay_25 = (1/app.Fs)*(n_delay_25);
            r_25 = t_delay_25*34326;
            if r_25 == 0
                r_25 = 0.0001;
            end
            
            n_delay_34 = app.peak_3 - app.peak_4;
            t_delay_34 = (1/app.Fs)*(n_delay_34);
            r_34 = t_delay_34*34326;
            if r_34 == 0
                r_34 = 0.0001;
            end
            
            n_delay_35 = app.peak_3 - app.peak_5;
            t_delay_35 = (1/app.Fs)*(n_delay_35);
            r_35 = t_delay_35*34326;
            if r_35 == 0
                r_35 = 0.0001;
            end
            
            n_delay_45 = app.peak_4 - app.peak_5;
            t_delay_45 = (1/app.Fs)*(n_delay_45);
            r_45 = t_delay_45*34326;
            if r_45 == 0
                r_45 = 0.0001;
            end
            
            %positions of the mics
            
            x1 = [0; 0];
            x2 = [0; 600];
            x3 = [600; 0];
            x4 = [600; 600];
            x5 = [300; 0];
            
            A = [2*((x2-x1).') -2*r_12 0 0 0;
                2*((x3-x1).') 0 -2*r_13 0 0;
                2*((x4-x1).') 0 0 -2*r_14 0;
                2*((x5-x1).') 0 0 0 -2*r_15;
                2*((x3-x2).') 0 -2*r_23 0 0;
                2*((x4-x2).') 0 0 -2*r_24 0;
                2*((x5-x2).') 0 0 0 -2*r_25;
                2*((x4-x3).') 0 0 -2*r_34 0;
                2*((x5-x3).') 0 0 0 -2*r_35;
                2*((x5-x4).') 0 0 0 -2*r_45];
            
            b_1 = [r_12^2-(norm(x1))^2+(norm(x2))^2;
                r_13^2-(norm(x1))^2+(norm(x3))^2;
                r_14^2-(norm(x1))^2+(norm(x4))^2;
                r_15^2-(norm(x1))^2+(norm(x5))^2;
                r_23^2-(norm(x2))^2+(norm(x3))^2;
                r_24^2-(norm(x2))^2+(norm(x4))^2;
                r_25^2-(norm(x2))^2+(norm(x5))^2;
                r_34^2-(norm(x3))^2+(norm(x4))^2;
                r_35^2-(norm(x3))^2+(norm(x5))^2;
                r_45^2-(norm(x4))^2+(norm(x5))^2];
            
            A_t = inv((A.')*A);
            app.y_out = (A_t)*(A.')*b_1;
            
            discard_estimation(app);
            
        end
        
        function discard_estimation(app)
            
            app.est_pos_2 = app.est_pos_1;
            app.est_pos_1 = app.y_out;
            
            estimation_distance = [app.est_pos_1(1)-app.est_pos_2(1) app.est_pos_1(2)-app.est_pos_2(2)];
            estimation_variance = norm(estimation_distance);
            
            if (app.est_pos_1(1) >= 600) || (app.est_pos_1(1) <= 0) || (app.est_pos_1(2) <= 0) || (app.est_pos_1(2) >= 600)
                app.est_pos_1 = app.est_pos_2;
            elseif (estimation_variance >= 200*app.speed && estimation_variance >= 70)
                app.est_pos_1 = app.est_pos_2;
            end
            
        end
        
        function datagen(app)
            app.y = EPOCommunications('receive','N16000'); %receive audio signal
        end
        
        function channelEstimation_prep(app)
            tempref = app.ref.reference;
            
            app.X = fft(tempref); %fft to get x in frequency domain
            ii = find(abs(app.X) > app.eps*max(abs(app.X))); %assuring the noise is filtered out
            %             app.G = zeros(length(app.X));
            
            for n = 1:length(app.X)
                for i = 1:length(ii)
                    if n == ii(i)
                        app.G(n)=1;
                        break
                    else
                        app.G(n)=0;
                    end
                end
            end
            app.G=app.G(:);
        end
        
        function channelEstimation(app)
            
            %             Ny = length(app.y(:,1));     %length of recorded signal
            %             Nx = length(app.ref);     %length of reference signal
            
            Y_1 = fft(app.y(:,1));         %fft to get y in frequency domain
            Y_2 = fft(app.y(:,2));
            Y_3 = fft(app.y(:,3));
            Y_4 = fft(app.y(:,4));
            Y_5 = fft(app.y(:,5));


            % channel mic 1
            H_1 = Y_1./app.X;
            H_1 = app.G.*H_1;
            TF = isnan(H_1);
            H_1(TF) = 0;
            app.h_1 = ifftshift(ifft(H_1));
            
            % Channel mic 2
            H_2 = Y_2./app.X;
            H_2 = app.G.*H_2;
            TF = isnan(H_2);
            H_2(TF) = 0;
            app.h_2 = ifftshift(ifft(H_2));
            
            %Channel mic 3
            H_3 = Y_3./app.X;
            H_3 = app.G.*H_3;
            TF = isnan(H_3);
            H_3(TF) = 0;
            app.h_3 = ifftshift(ifft(H_3));
            
            %Channel mic 4
            H_4 = Y_4./app.X;
            H_4 = app.G.*H_4;
            TF = isnan(H_4);
            H_4(TF) = 0;
            app.h_4 = ifftshift(ifft(H_4));
            
            %channel mic 5
            H_5 = Y_5./app.X;
            H_5 = app.G.*H_5;
            TF = isnan(H_5);
            H_5(TF) = 0;
            app.h_5 = ifftshift(ifft(H_5));
        end
        
        function firstPeak(app)
            
            %findpeaks 5 peaks
            [pks_1,locs_1] = findpeaks(abs(app.h_1));
            [pks_2,locs_2] = findpeaks(abs(app.h_2));
            [pks_3,locs_3] = findpeaks(abs(app.h_3));
            [pks_4,locs_4] = findpeaks(abs(app.h_4));
            [pks_5,locs_5] = findpeaks(abs(app.h_5));
            
            % allpeaks
            allpeaks_1 = find(pks_1>app.peak_eps*max(app.h_1));
            allpeaks_2 = find(pks_2>app.peak_eps*max(app.h_2));
            allpeaks_3 = find(pks_3>app.peak_eps*max(app.h_3));
            allpeaks_4 = find(pks_4>app.peak_eps*max(app.h_4));
            allpeaks_5 = find(pks_5>app.peak_eps*max(app.h_5));
            
            % locate all peaks
            app.peak_1 = locs_1(allpeaks_1(1));
            app.peak_2 = locs_2(allpeaks_2(1));
            app.peak_3 = locs_3(allpeaks_3(1));
            app.peak_4 = locs_4(allpeaks_4(1));
            app.peak_5 = locs_5(allpeaks_5(1));
        end
        
        function tracking(app) % purpose is to determine the speed and direction of ze car.
            app.old_estimated_x_position = app.estimated_x_position;
            app.old_estimated_y_position = app.estimated_y_position;
            app.estimated_x_position = app.est_pos_1(1);
            app.estimated_y_position = app.est_pos_1(2);
            %             app.estimated_x_position = app.kitt.position(1);
            %             app.estimated_y_position = app.kitt.position(2);
            
            dx = app.estimated_x_position - app.old_estimated_x_position;
            dy = app.estimated_y_position - app.old_estimated_y_position;
            
            %calculating new estimated facing
            estimated_direction = atan2(dy,dx);
            
            
            if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9)
                app.estimated_facing = app.estimated_facing + pi;
                %                 while app.estimated_facing < 0
                %                     app.estimated_facing = app.estimated_facing + 2*pi;
                %                 end
                %                 while app.estimated_facing >= 2*pi
                %                     app.estimated_facing = app.estimated_facing - 2*pi;
                %                 end
                facing_error = estimated_direction - app.estimated_facing;
                while facing_error < -pi
                    facing_error = facing_error + 2*pi;
                end
                while facing_error >= pi
                    facing_error = facing_error - 2*pi;
                end
                if facing_error >= pi/4
                    facing_error = pi/4;
                elseif facing_error <= -pi/4
                    facing_error = -pi/4;
                end
                if app.direction == 150
                    app.estimated_facing = app.estimated_facing + (facing_error)*0.5;
                else
                    app.estimated_facing = app.estimated_facing + (facing_error)*0.5;
                end
                app.estimated_facing = app.estimated_facing + pi;
                %                 while app.estimated_facing < 0
                %                     app.estimated_facing = app.estimated_facing + 2*pi;
                %                 end
                %                 while app.estimated_facing >= 2*pi
                %                     app.estimated_facing = app.estimated_facing - 2*pi;
                %                 end
            else
                facing_error = estimated_direction - app.estimated_facing;
                while facing_error < -pi
                    facing_error = facing_error + 2*pi;
                end
                while facing_error >= pi
                    facing_error = facing_error - 2*pi;
                end
                if app.direction == 150
                    app.estimated_facing = app.estimated_facing + (facing_error)*0.5;
                else
                    app.estimated_facing = app.estimated_facing + (facing_error)*0.4;
                end
            end
            
            while app.estimated_facing < 0
                app.estimated_facing = app.estimated_facing + 2*pi;
            end
            while app.estimated_facing >= 2*pi
                app.estimated_facing = app.estimated_facing - 2*pi;
            end
            
            %             %calculate new estimated speed
            %             if app.steering_radius == 0
            %                 app.estimated_speed = sqrt((dx/100)^2 + (dy/100)^2) / app.tt; % conversion to SI  !!!
            %             else
            %                 app.estimated_speed = app.steering_radius * 2 * asin(sqrt((dx/100)^2 + (dy/100)^2)/(2*app.steering_radius))/app.tt; % conversion to SI  !!!
            %             end
            % saving last positions
            %             for n = 1:9
            %                 app.prev_position(n,:) = app.prev_position(n+1,:);
            %             end
            %             app.prev_position(10,:) = [app.estimated_x_position app.estimated_y_position];
            
        end
        
        function feedback_control(app)
            % averaging last positions with weighted average
            %             a = [0 0];
            %             for n = 1:10
            %                 if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9)
            %                     a = a + (app.prev_position(n,:) + [100*abs(app.speed)*app.tt2*(10-n)*cos(app.facing + pi)  100*abs(app.speed)*app.tt2*(10-n)*sin(app.facing + pi)]) * n;
            %
            %                 else
            %                     a = a + (app.prev_position(n,:) + [100*abs(app.speed)*app.tt2*(10-n)*cos(app.facing)  100*abs(app.speed)*app.tt2*(10-n)*sin(app.facing)]) * n;
            %
            %                 end
            %             end
            %             a = a/55;
            
            % correcting position
            % this is now a box, change to circle
            if sqrt((app.x_mid - app.estimated_x_position)^2 + (app.y_mid - app.estimated_y_position)^2) > 30
                app.x_position = app.x_position + (app.estimated_x_position - cos(app.facing)*app.car_length/2 - app.x_position)*0.05;
                app.y_position = app.y_position + (app.estimated_y_position - sin(app.facing)*app.car_length/2 - app.y_position)*0.05;
            end
            
            %correcting facing
            facing_error = app.estimated_facing - app.facing;
            while facing_error < -pi
                facing_error = facing_error + 2*pi;
            end
            while facing_error >= pi
                facing_error = facing_error - 2*pi;
            end
            
            if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9)
                if abs(app.facing - app.estimated_facing) > 0.05 && app.speed > 0.1 && app.flag_circle == 2
                    app.facing = app.facing + (facing_error)*0.02;
                end
            else
                if abs(app.facing - app.estimated_facing) > 0.05 && app.speed > 0.1 && app.flag_circle == 2
                    app.facing = app.facing + (facing_error)*0.04;
                end
            end
            %             if abs(app.facing - app.estimated_facing) > 0.05 && app.speed > 0.1 && app.flag_circle == 2
            %                 app.facing = app.facing + (facing_error)*0.5;
            %             end
            
            
            %             %correcting speed
            %             if app.estimated_speed < 0.005
            %                 app.speed = app.speed + (app.estimated_speed - app.speed)*0.2;
            %             end
            %             if app.estimated_speed/app.speed > 1.1 || app.speed/app.estimated_speed > 1.1
            %                 app.speed = app.speed + (app.estimated_speed - app.speed)*0.2;
            %             end
        end
        
        function two_pi_normer(app)
            while app.facing_c >= 2*pi
                app.facing_c = app.facing_c - 2*pi;
            end
            while app.facing_c < 0
                app.facing_c = app.facing_c + 2*pi;
            end
        end
        function min_pi_to_pi_normer(app)
            while app.facing_c >= pi
                app.facing_c = app.facing_c - 2*pi;
            end
            while app.facing_c < -pi
                app.facing_c = app.facing_c + 2*pi;
            end
        end
        function route_parameters(app) %only called on plot button
            % circle center to waypoint
            app.S_left = [app.x_waypoint_1-app.CircleLeft(1) app.y_waypoint_1-app.CircleLeft(2)];
            app.S_right = [app.x_waypoint_1-app.CircleRight(1) app.y_waypoint_1-app.CircleRight(2)];
            
            % magnitude of Sdistance_circle_left
            app.distance_S_left = norm(app.S_left);
            app.distance_S_right = norm(app.S_right);
        end
        
        function circle_distance(app)
            % angles
            a_left = acos(app.R/app.distance_S_left);
            a_right = acos(app.R/app.distance_S_right);
            
            b_left = atan2(app.S_left(2),app.S_left(1));
            b_right = atan2(app.S_right(2),app.S_right(1));
            
            % setting facing_c for c1
            app.facing_c = app.facing + pi/2;
            two_pi_normer(app);
            if b_right - a_right < 0.1 && app.x_waypoint_1 > app.x_position % correction for b-a < 0, with margin to prevent weird behavior in the case of an overshoot.
                min_pi_to_pi_normer(app);
                c_1 = (app.facing_c) - (b_right - a_right);
            elseif b_right >= 0
                c_1 = (app.facing_c) - (b_right - a_right);
            else
                c_1 = (app.facing_c) - 2*pi - (b_right - a_right);
            end
            
            % setting facing_c for c2
            app.facing_c = app.facing - pi/2;
            if b_left >= pi/2 || b_left <= -pi/2
                two_pi_normer(app);
                if b_left >= 0
                    c_2 = (b_left + a_left) - (app.facing_c);
                else
                    c_2 = 2*pi + (b_left + a_left) - (app.facing_c);
                end
            else
                min_pi_to_pi_normer(app);
                c_2 = (b_left + a_left) - (app.facing_c);
            end
            
            % setting facing_c for c3
            app.facing_c = app.facing - pi/2;
            if b_left >=0
                min_pi_to_pi_normer(app);
                c_3 = (b_left - a_left) - (app.facing_c);
            else
                two_pi_normer(app);
                c_3 = 2*pi + (b_left - a_left) - (app.facing_c);
            end
            
            % setting facing_c for c4
            app.facing_c = app.facing - pi/2;
            if b_left >= 0
                two_pi_normer(app);
            else
                min_pi_to_pi_normer(app);
            end
            c_4 = (app.facing_c) - (b_left + a_left);
            
            % setting facing_c for c5
            app.facing_c = app.facing + pi/2;
            if b_right - a_right <= 0
                two_pi_normer(app);
                c_5 = 2*pi - (app.facing_c) + (b_right - a_right);
            else
                min_pi_to_pi_normer(app);
                c_5 = (b_right - a_right) - (app.facing_c);
            end
            
            % setting facing_c for c6
            app.facing_c = app.facing + pi/2;
            if b_right >= 0
                two_pi_normer(app);
            else
                min_pi_to_pi_normer(app);
            end
            c_6 = (app.facing_c) - (a_right + b_right);
            
            
            % distance
            if (app.waypoint_1_position == 1)
                app.distance_circle = c_1*app.R;
            elseif (app.waypoint_1_position == 2)
                app.distance_circle = c_2*app.R;
            elseif (app.waypoint_1_position == 3)
                app.distance_circle = c_3*app.R;
            elseif (app.waypoint_1_position == 4)
                app.distance_circle = c_4*app.R;
            elseif (app.waypoint_1_position == 5)
                app.distance_circle = c_5*app.R;
            elseif (app.waypoint_1_position == 6)
                app.distance_circle = c_6*app.R;
            end
        end
        
        function circle_data(app)
            %Determine center points of the right and left circle
            app.CircleLeft = [app.x_position-app.R*sin(app.facing) app.y_position+app.R*cos(app.facing)];
            app.CircleRight = [app.x_position+app.R*sin(app.facing) app.y_position-app.R*cos(app.facing)];
            %             Determine point on circle for plotting
            p=2*pi();
            ang = linspace(0, p);
            app.xp1=app.x_position-app.R*sin(app.facing)+app.R*cos(ang);
            app.yp1=app.y_position+app.R*cos(app.facing)+app.R*sin(ang);
            app.xp2=app.x_position+app.R*sin(app.facing)-app.R*cos(ang);
            app.yp2=app.y_position-app.R*cos(app.facing)-app.R*sin(ang);
            %             circ1=[app.xp1;app.yp1];
            %             circ2=[app.xp2;app.yp2];
        end
        
        function check_waypoint_field(app) %only called on plot button


            %Determine the relative angle from the rear point of the car to
            %the waypoint and place it in a range 0-2pi
            app.car_waypoint_angle=atan2((app.y_waypoint_1-app.y_position), (app.x_waypoint_1-app.x_position));
            if(app.car_waypoint_angle <0)
                app.car_waypoint_angle =2*pi+app.car_waypoint_angle;
            end
            
            %take the modulo of car facing angle and place it in a range 0-2pi
            app.facing_mod=mod(app.facing, 2*pi);
            app.angle_difference = (app.car_waypoint_angle-app.facing_mod);
            if(app.angle_difference <0)
                app.angle_difference =2*pi+app.angle_difference;
            end
            
            %%This code determines where the waypoint is with respect to
            %%the car:
            % 0: error
            % 1: inside the left circle
            % 2: inside the right circle
            % 3: upper left quadrant
            % 4: lower left quadrant
            % 5: lower richt quadrant
            % 6: upper richt quadrant
            % 7: straight ahead
            % 8: exactly left
            % 9: straight below
            % 10: exactly right
            if app.flag_selection == 0 && (app.x_waypoint_1 ~= 0 || app.y_waypoint_1 ~= 0)
                if app.distance_S_left <= app.R
                    app.waypoint_1_position = 1;
                elseif app.distance_S_right <= app.R
                    app.waypoint_1_position = 2;
                elseif app.angle_difference>0 && app.angle_difference<0.5*pi
                    app.waypoint_1_position = 3;
                elseif app.angle_difference>0.5*pi && app.angle_difference<1*pi
                    app.waypoint_1_position = 4;
                elseif app.angle_difference>1*pi && app.angle_difference<1.5*pi
                    app.waypoint_1_position = 5;
                elseif app.angle_difference>1.5*pi && app.angle_difference<2*pi
                    app.waypoint_1_position = 6;
                elseif app.angle_difference == 0
                    app.waypoint_1_position = 7;
                elseif app.angle_difference == 0.5*pi
                    app.waypoint_1_position = 3; % from 8 --> 3
                elseif app.angle_difference == 1*pi
                    app.waypoint_1_position = 9;
                elseif app.angle_difference == 1.5*pi
                    app.waypoint_1_position = 6; % from 10 --> 6
                end
                app.flag_selection = 1;
            elseif app.distance < 15 && app.speed < 0.1
                app.flag_selection = 0;
            end
        end
        
        function bangbangcontrol_straightline(app)
            
            time_delay = 4*app.tt;
            
            if app.speed >= 0
                total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
                total_force = app.force - app.b*app.speed - app.c*app.speed^2;
            else
                total_force_a = -abs(app.force) - app.b*app.speed + app.c*app.speed^2;
                total_force = app.force - app.b*app.speed + app.c*app.speed^2;
            end
            projected_distance = app.distance - 100*abs(app.speed)*time_delay - 100*abs(total_force_a)*time_delay^2/(2*app.m); %in CM!!!!!!
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            braking_velocity = @(t) exp(-(100/112)*t)*(2+abs(projected_speed))-2;
            upperlim = -112*log(10/(10+5*abs(projected_speed)))/100;
            braking_distance = integral(braking_velocity,0,upperlim);
            
            if (100*braking_distance >= projected_distance || app.flag_straight == 1)
                if abs(app.speed) <= 0.5 || app.distance <= 2
                    app.input_force = 150;  % "coasting" state when nearing endpoint
                    app.flag_straight = 2;
                else
                    if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9 || app.forward_flag == 1)
                        app.input_force = 165;  % "hard brake" state when nearing endpoint
                        app.forward_flag = 1;
                    else
                        app.input_force = 135;
                        app.forward_flag = 0;
                    end
                    app.flag_straight = 1;
                end
            elseif app.flag_straight == 0
                if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9 || app.forward_flag == 1)
                    app.input_force = 145;      % "go" state
                    app.forward_flag = 1;
                else
                    app.input_force = 155;
                    app.forward_flag = 0;
                end
                
                %                 app.direction = 150;
                app.flag_straight = 0;
            else
                app.input_force = 150;      % Idle state
                app.flag_straight = 2;
            end
        end
        
        function bangbangcontrol_circle(app)
            
            time_delay = 3*app.tt;
            
            if app.speed >= 0
                total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
                total_force = app.force - app.b*app.speed - app.c*app.speed^2;
            else
                total_force_a = -abs(app.force) - app.b*app.speed + app.c*app.speed^2;
                total_force = app.force - app.b*app.speed + app.c*app.speed^2;
            end
            
            projected_distance = abs(app.distance_circle) - 100*abs(app.speed)*time_delay - 100*abs(total_force_a)*time_delay^2/(2*app.m); %in CM!!!!!!
            
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            braking_velocity = @(t) exp(-(100/112)*t)*(2+abs(projected_speed))-2;
            upperlim = -112*log(10/(10+5*abs(projected_speed)))/100;
            braking_distance = integral(braking_velocity,0,upperlim);
            
            if (100*braking_distance >= projected_distance || app.flag_circle == 1)
                if abs(app.speed) <= 0.1
                    app.input_force = 150;  % "coasting" state when nearing endpoint
                    app.flag_circle = 2;
                else
                    if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5)
                        app.input_force = 165;
                    else
                        app.input_force = 135;
                    end
                    
                    app.flag_circle = 1;
                end
            elseif app.flag_circle == 0
                if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5)
                    app.input_force = 149;
                else
                    app.input_force = 151;
                end
                
                app.flag_circle = 0;
                
                if (app.waypoint_1_position == 2 || app.waypoint_1_position == 3 || app.waypoint_1_position == 4)
                    app.direction = 200;
                elseif (app.waypoint_1_position == 1 || app.waypoint_1_position == 5 || app.waypoint_1_position == 6)
                    app.direction = 100;
                end
            else
                app.input_force = 150;      % Idle state
                app.flag_circle = 2;
                app.direction = 150;
            end
        end
        
        function steering_control(app)
            waypoint_direction = atan2(app.y_waypoint_1-app.y_position,app.x_waypoint_1-app.x_position);
            
            if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9)
                steering_feedback = waypoint_direction - (app.facing + pi);
                while steering_feedback < -pi
                    steering_feedback = steering_feedback + 2*pi;
                end
                while steering_feedback >= pi
                    steering_feedback = steering_feedback - 2*pi;
                end
                
                app.direction = 150 - (steering_feedback) * 40;
            else
                steering_feedback = waypoint_direction - app.facing;
                while steering_feedback < -pi
                    steering_feedback = steering_feedback + 2*pi;
                end
                while steering_feedback >= pi
                    steering_feedback = steering_feedback - 2*pi;
                end
                
                app.direction = 150 + (steering_feedback) * 40;
            end
            route_parameters(app)
            
            if app.distance_S_left <= app.R || app.distance_S_right <= app.R
                app.direction = 150;
                %                 app.flag_straight = 2;
            end
            
            if app.direction > 200
                app.direction = 200;
            end
            if app.direction < 100
                app.direction = 100;
            end
        end
        
        function ploteverything(app)
            
            %             delete(app.h);  %delete text before writing it again
            
            % Plot car front and back location, lines and arena
            
            %             delete(app.car_model_circle);
            delete(app.front_line);
            delete(app.back_line);
            %             app.car_model_circle  = plot(app.UIAxes,  app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            app.front_line  = plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--', app.x_box, app.y_box, 'r-');
            app.back_line = plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
            
            %             delete(app.mic_1);
            %             delete(app.mic_2);
            %             delete(app.mic_3);
            %             delete(app.mic_4);
            %             delete(app.mic_5);
            %
            %             app.mic_1 = plot(app.UIAxes, 600, 600, 'x', 'color', 'r', 'LineWidth', 2);
            %             app.mic_2 = plot(app.UIAxes, 600, 0, 'x', 'color', 'r', 'LineWidth', 3);
            %             app.mic_3 = plot(app.UIAxes, 0, 600, 'x', 'color', 'r', 'LineWidth', 3);
            %             app.mic_4 = plot(app.UIAxes, 0, 0, 'x', 'color', 'r', 'LineWidth', 3);
            %             app.mic_5 = plot(app.UIAxes, 300, 0, 'x', 'color', 'r', 'LineWidth', 3);
            
            %to plot the cirles
            
            delete(app.circle_r);
            delete(app.circle_l);
            app.circle_r = plot(app.UIAxes, app.xp1, app.yp1, "b");
            app.circle_l = plot(app.UIAxes, app.xp2, app.yp2, "b");


            % Plot the text
            %             app.h(1) = text(app.UIAxes,-90,490,app.s1);
            %             app.h(2) = text(app.UIAxes,-90,470,app.s2);
            %             app.h(3) = text(app.UIAxes,-90,450,app.s3);
            %             app.h(4) = text(app.UIAxes,-90,430,app.s4);
            %             app.h(5) = text(app.UIAxes,-90,410,app.s5);
            %             app.h(6) = text(app.UIAxes,-90,390,app.s6);
            %             app.h(7) = text(app.UIAxes,-90,370,app.s7);
            %             app.h(8) = text(app.UIAxes,-90,350,app.s8);
            %             app.h(9) = text(app.UIAxes,-90,330,app.s9);
            %             app.h(10) = text(app.UIAxes,-90,310,app.s10);
            %             app.h(11) = text(app.UIAxes, -90, 290, app.s11);
            %             app.h(12) = text(app.UIAxes, -90, 270, app.s12);
            %             app.h(13) = text(app.UIAxes, -90, 230, app.s13);
            %             app.h(14) = text(app.UIAxes, -90, 200, app.s14);
            %             app.h(15) = text(app.UIAxes, -90, 210, app.s15);
            %             app.h(16) = text(app.UIAxes, -90, 190, app.s16);
            %             app.h(17) = text(app.UIAxes, -90, 270, app.s17);
            
            % plot the waypoint
            %             if (app.waypoint >= 1)
            %                 plot(app.UIAxes, app.x_waypoint_1,app.y_waypoint_1, '.' ,'MarkerSize', 15, 'color', 'g')
            %             end
            % show car
            % query car status (kitt automatic updates as time progresses)
            x_pos = app.est_pos_1;
            
            
            plot(app.UIAxes,x_pos(1),x_pos(2),'bo');		% draw car position as 'o'
            %             delete(app.hand)				% remove old outline of car
            %             app.hand = plot(app.kitt,app.UIAxes);		% draw outline of the car
            
            
            % plot the waypoint
            if (app.waypoint >= 1)
                plot(app.UIAxes, app.x_waypoint_1,app.y_waypoint_1, '.' ,'MarkerSize', 15, 'color', 'g')
            end
            %             % update plot of waypoints (ERROR not efficient to do this within the loop)
            %             delete(app.hw);
            %             app.hw = plot(app.kitt.Waypoints,app.UIAxes);
            
            % update plot of obstacles (ERROR not efficient to do this within the loop)
            delete(app.hs);
            app.hs = plot(app.kitt.Sensors,app.UIAxes);	% plot obstacles and arena bounding box
            
            app.car_outline = polyshape([app.x_position-21 app.x_position-21 app.x_position+21 app.x_position+21],[app.y_position-9.15 app.y_position+app.car_length+9.15 app.y_position+app.car_length+9.15 app.y_position-9.15]);
            app.car_rotation = (app.facing - pi/2)*180/pi;
            app.car_model = rotate(app.car_outline, app.car_rotation, [app.x_position app.y_position]);
            delete(app.car_model_plot);
            app.car_model_plot = plot(app.UIAxes, app.car_model);
            app.car_model_plot.FaceColor = 'b';
            
            drawnow limitrate nocallbacks;	% flush plot buffer
        end
        
        function newstuff(app)
            app.x_front = cos(app.facing)*app.car_length + app.x_position;
            app.y_front = sin(app.facing)*app.car_length + app.y_position;
            
            app.x_mid = cos(app.facing)*app.car_length/2 + app.x_position;
            app.y_mid = sin(app.facing)*app.car_length/2 + app.y_position;
            
            app.x_line = app.x_front + (1000*cos(app.facing));
            app.y_line = app.y_front + (1000*sin(app.facing));
            app.x_line_2 = app.x_front - (1000*cos(app.facing));
            app.y_line_2 = app.y_front - (1000*sin(app.facing));
            
        end


        function collision(app)
            % Checks if the car is outside of the arena
            if (app.x_position >= 600 || app.x_front >= 600 ) || (app.x_position <= 0 || app.x_front <= 0) || (app.y_position <= 0 || app.y_front <= 0) || (app.y_position >= 600 || app.y_front >= 600)
                app.crash = 1;      % If crashed set crash to 1
                app.speed = 0;
                stop(app.TDOALoop);
                stop(app.Loop);     % If crashed stop the loop
                % Plot the crash location
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.x_box, app.y_box, 'r-');
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
                
                %                 app.h(1) = text(app.UIAxes,-90,490,app.s1);
                %                 app.h(2) = text(app.UIAxes,-90,470,app.s2);
                %                 app.h(3) = text(app.UIAxes,-90,450,app.s3);
                %                 app.h(4) = text(app.UIAxes,-90,430,app.s4);
                %                 app.h(5) = text(app.UIAxes,-90,410,app.s5);
                %                 app.h(6) = text(app.UIAxes,-90,390,app.s6);
                %                 app.h(7) = text(app.UIAxes,-90,370,app.s7);
                %                 app.h(8) = text(app.UIAxes,-90,350,app.s8);
                %                 app.h(9) = text(app.UIAxes,-90,330,app.s9);
                %                 app.h(10) = text(app.UIAxes,-90,310,app.s10);
                %                 app.h(11) = text(app.UIAxes, -90, 290, app.s11);
                %                 app.h(12) = text(app.UIAxes, -90, 270, app.s12);
                %                 app.h(13) = text(app.UIAxes, -90, 250, app.s13);
                %                 app.h(14) = text(app.UIAxes, -90, 230, app.s14);
            end
        end
        
        function velocity(app)
            % Speed is calculated in m/s now, so *100 is added on the display in the figure
            app.force = (app.input_force - 150)*2/3;
            if app.speed >= 0
                app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m - app.c*app.speed^2*app.tt/app.m;
            else
                app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m + app.c*app.speed^2*app.tt/app.m;
            end
            if abs(app.speed) < 0.001
                app.deadspeed = 0;              % If car goes VERY slow set speed to 0 (deadzone)
            else
                app.deadspeed = app.speed;
            end
        end
        
        function waypointdistance(app)
            % Calculate the distance to the waypoint
            % Only supports 1 waypoint as of now
            app.x_distance = abs(app.x_waypoint_1 - app.x_mid);
            app.y_distance = abs(app.y_waypoint_1 - app.y_mid);
            
            app.distance = sqrt(app.x_distance^2 + app.y_distance^2);
        end
        
        function initEPOcomm(app)
            % initializations
            aaaa = [app.startx; app.starty];
            aaa = mat2str(aaaa);
            EPOCommunications('init',['X' aaa]);	% initial position of car
            
            ddd = mat2str([cos(app.start_angle); sin(app.start_angle)]);
            EPOCommunications('init',['D' ddd]);	% initial direction of car
            
            % define positions of microphones
            P = [
                0 0 600 600 300;
                0 600 0 600 0;
                20 20 20 20 70];
            pp = mat2str(P);
            EPOCommunications('init',['P' pp]);% initialize positions of mics
            EPOCommunications('init','J40000');	% set sample rate of mics
            EPOCommunications('init','N2000');	% set number of samples to return
            
            
            % define arena boundary
            B1 = [
                -50 650 650 -50;
                -50 -50 650 650];	% outer arena boundary (outside bounding box)
            NAN = [NaN; NaN];	% spacer between objects
            B2 = [
                -21 621 621 -21;
                -21 -21 621 621];	% inner arena boundary (hole since it overlaps B1)
            bb = mat2str([B1 NAN B2]);
            EPOCommunications('init',['O' bb]);	% initialize arena boundary
            
            %             % define obstacle position (polygons)
            %             O1 = [
            %                 200 200 260 230;
            %                 200 240 240 200];	% first obstacle
            %             O2 = [
            %                 350 380 410 410;
            %                 200 240 240 200];	% second obstacle
            %             oo = mat2str([O1 NAN O2]);
            %             EPOCommunications('init',['O' oo]);	% initialize obstacles
            %
            %             % define two waypoints
            %             W = [
            %                 100   400;
            %                 100   400];
            %             ww = mat2str(W);
            %             EPOCommunications('init',['W' ww]);	% initialize waypoints
            
            
            % create kitt!
            app.kitt = EPOCommunications('open');	% create kitt (P = public, with access
       					% to position, orientation, velocity)
            
            EPOCommunications('transmit', 'A1'); % switch on audio beacon
            
            EPOCommunications('transmit', 'B5000'); % set the bit frequency
            EPOCommunications('transmit', 'F20000');% set the carrier frequency
            EPOCommunications('transmit', 'R2000'); % set the repetition count
            EPOCommunications('transmit', 'C0xE04869A5'); % set the audio code
        end
        
        function TDOALoopFcn(app,~,~)
            if (app.WButton.Value == 1 )
                app.t = toc(app.now);
                app.TimeEditField.Value = app.t;
                
                TDOA_estimation(app);
                tracking(app);
                if app.feed_flag >= 10
                    feedback_control(app);
                else
                    app.feed_flag = app.feed_flag + 1;
                end
            end
            %             app.count = app.count + 1;
            
        end
        function LoopFcn(app,~,~)
            
            hold(app.UIAxes,'on')   % Keeps old dot locations and arena
            
            collision(app);         % Checks every 0.1 seconds if the car has gone outside the arena
            circle_data(app);
            if (app.waypoint >= 1)
                waypointdistance(app);  % Checks every 0.1 seconds the distance to the waypoint if a waypoint is present
                circle_distance(app);
            end
            
            velocity(app);
            if (app.WButton.Value == 1 )
                %                 TDOA_estimation(app);
                %                 tracking(app);
                %                 if app.feed_flag >= 10
                %                     feedback_control(app);
                %                 else
                %                     app.feed_flag = app.feed_flag + 1;
                %                 end
            end


            %             app.s1 = sprintf('force: %g N', app.input_force);   %kitt.force
            %             app.s2 = sprintf('velocity: %g cm/s', app.speed*100);   %kitt.velocity
            %             app.s3 = sprintf('angle: %g', app.direction);       %kitt.angle
            %             app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            %             app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            %             app.s6 = sprintf('time: %0.2f s',app.t);            %time
            %             app.s7 = sprintf('crash: %g', app.crash);           %carchrash(kitt);
            %             app.s8 = sprintf('zaps: ');                         %kitt.numzaps;
            %             app.s9 = sprintf('tuning flag: %g cm', app.tuning_flag);
            %             app.s10 = sprintf('Waypoint location: %g', app.waypoint_1_position);
            %             app.s11 = sprintf('angle difference: %g', app.angle_difference/(2*pi)*360);
            %             app.s12 = sprintf('car waypoint angle: %g', app.car_waypoint_angle/(2*pi)*360);
            %             app.s13 = sprintf('facing: %g', app.facing);
            %             app.s14 = sprintf('est facing: %g', app.estimated_facing);
            
            % Determine new position based on force and angle
            if app.direction == 150
                app.steering_radius = 0;
            else
                app.steering_radius = 0.335/tan((app.direction-150)*0.45/50);
            end
            if app.steering_radius == 0
                app.x_position = app.x_position + 100*app.speed*app.tt * cos(app.facing);
                app.y_position = app.y_position + 100*app.speed*app.tt * sin(app.facing);
                app.facing = app.facing;
            else
                app.x_position = app.x_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * cos(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.y_position = app.y_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * sin(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.facing = app.facing + app.speed*app.tt/app.steering_radius;
            end
            %             app.facing = mod(app.facing, 2*pi);
            %             app.TimeEditField.Value = app.facing;
            newstuff(app);
            
            % If statement to prevent plotting another dot outside the arena
            if (app.crash == 0)
                ploteverything(app);
            end
            
            
            if (app.WButton.Value == 1 )
                if (app.flag_circle == 2)
                    bangbangcontrol_straightline(app);
                    steering_control(app);
                    if app.flag_straight == 2 && app.distance > 30 && app.flag_stop <= 3
                        app.flag_circle = 0;
                        app.flag_straight = 0;
                        app.flag_selection = 0;
                        app.forward_flag = 0;
                        app.WButton.Value = 1;
                        app.feed_flag = 0;
                        app.flag_stop = 0;
                        
                        route_parameters(app);
                        check_waypoint_field(app);
                    elseif app.flag_straight == 2 && app.distance <= 30
                        app.flag_stop = app.flag_stop + 1;
                        
                    end
                    if app.flag_straight == 2 && abs(app.speed) <= 0.005 && (app.AButton.Value == 1 || (app.BButton.Value == 1 && app.way_flag == 1))
                        %                         app.hand = plot(app.kitt,app.UIAxes);
                        app.kitt = EPOCommunications('close');
                        if app.AButton.Value == 1
                            dis = sqrt((app.kitt.x(1) - app.x_waypoint_1)^2 + (app.kitt.x(2) - app.y_waypoint_1)^2);
                            app.Finaldistance1EditField.Value = dis;
                        elseif app.BButton.Value == 1
                            dis = sqrt((app.kitt.x(1) - app.x_waypoint_1)^2 + (app.kitt.x(2) - app.y_waypoint_1)^2);
                            app.Finaldistance2EditField.Value = dis;
                        end
                        app.car_outline = polyshape([app.kitt.x(1)-21 app.kitt.x(1)-21 app.kitt.x(1)+21 app.kitt.x(1)+21],[app.kitt.x(2)-9.15 app.kitt.x(2)+app.car_length+9.15 app.kitt.x(2)+app.car_length+9.15 app.kitt.x(2)-9.15]);
                        app.car_rotation = (atan2(app.kitt.d(2),app.kitt.d(1)) - pi/2)*180/pi;
                        app.car_model = rotate(app.car_outline, app.car_rotation, [app.kitt.x(1) app.kitt.x(2)]);
                        app.car_model_plot = plot(app.UIAxes, app.car_model);
                        app.car_model_plot.FaceColor = 'r';
                        stop(app.TDOALoop);
                        stop(app.Loop);
                    elseif app.flag_straight == 2 && abs(app.speed) <= 0.005 && app.BButton.Value == 1 && app.way_flag == 0
                        app.flag_circle = 0;
                        app.flag_straight = 0;
                        app.flag_selection = 0;
                        app.forward_flag = 0;
                        app.WButton.Value = 1;
                        app.feed_flag = 0;
                        app.flag_stop = 0;
                        app.way_flag = 1;
                        
                        app.kitt = EPOCommunications('close');
                        if app.BButton.Value == 1
                            dis = sqrt((app.kitt.x(1) - app.x_waypoint_1)^2 + (app.kitt.x(2) - app.y_waypoint_1)^2);
                            app.Finaldistance1EditField.Value = dis;
                        end
                        app.startx = app.kitt.x(1);
                        app.starty = app.kitt.x(2);
                        app.start_angle = atan2(app.kitt.d(2),app.kitt.d(1));
                        initEPOcomm(app);
                        
                        app.y_waypoint_1 = app.Way2_yEditField.Value;
                        app.x_waypoint_1 = app.Way2_xEditField.Value;
                        
                        route_parameters(app);
                        check_waypoint_field(app);
                    end
                else
                    bangbangcontrol_circle(app);
                end
            end
            
            
            app.kitt.force = app.input_force;
            app.kitt.angle = app.direction;
            
            % Clear the WASD control keys from their input
            %app.WButton.Value = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialize EPO comm
            addpath carmodel -begin
            newstuff(app)
            app.startx = app.x_mid;
            app.starty = app.y_mid;
            app.start_angle = app.facing;
            initEPOcomm(app);
            
            channelEstimation_prep(app);
            
            % Gives the right axis' on startup
            app.UIAxes.YLim = [-100 700];
            app.UIAxes.XLim = [-100 700];
            
            % Plot everything
%             % Plot car front and back location, lines and arena
%             plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
%             plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--');
%             plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
%             plot(app.UIAxes,[0 600 600 0 0],[0 0 600 600 0],'--');
%             plot(app.UIAxes,  app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');

            
            %             % show initial position of kitt car
%             app.hand = plot(app.kitt, app.UIAxes);		% draw outline of the car
            app.hs = plot(app.kitt.Sensors,app.UIAxes);	% plot obstacles and arena bounding box
            plot(app.kitt.Micarray, app.UIAxes);		% plot mics as "x"
            %             app.hw = plot(app.kitt.Waypoints,app.UIAxes);	% plot waypoints as "o"
            
            % Prints the initial texts in the plot
            
            %             app.s1 = sprintf('force: 0 N');
            %             app.s2 = sprintf('velocity: 0 m/s');
            %             app.s3 = sprintf('angle: 0 ');
            %             app.s4 = sprintf('distL: %g  cm', app.x_position);
            %             app.s5 = sprintf('distR: %g cm', app.y_position);
            %             app.s6 = sprintf('time: 0 s');
            %
            %             app.h(1) = text(app.UIAxes,5,490,app.s1);
            %             app.h(2) = text(app.UIAxes,5,470,app.s2);
            %             app.h(3) = text(app.UIAxes,5,450,app.s3);
            %             app.h(4) = text(app.UIAxes,5,430,app.s4);
            %             app.h(5) = text(app.UIAxes,5,410,app.s5);
            %             app.h(6) = text(app.UIAxes,5,390,app.s6);
            
            drawnow limitrate nocallbacks;	% flush plot buffer
            % Create timer object
            app.Loop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt, ...                % Period of the function
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LoopFcn);           % Specify callback function
            % Create timer object
            app.TDOALoop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt2, ...                % Period of the function
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.TDOALoopFcn);           % Specify callback function
            
            % Start the loop
            start(app.TDOALoop);
            % Start the loop
            start(app.Loop);
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
%                 case 'a'
%                     app.AButton.Value = 1;
%                     if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
%                         if (app.direction < 200)
%                             app.direction = app.direction + 2;
%                         end
%                     end
                case 'w'
                    if (app.WButton.Value == 0)
                        app.WButton.Value = 1;
                    else
                        app.WButton.Value = 0;
                    end
                    %if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                    %    if (app.input_force < 165)
                    %        app.input_force = app.input_force + 1;
                    %    end
                    %end
%                 case 's'
%                     app.SButton.Value = 1;
%                     if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
%                         if (app.input_force > 135)
%                             app.input_force = app.input_force - 1;
%                         end
%                     end
%                 case 'd'
%                     app.DButton.Value = 1;
%                     if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
%                         if (app.direction > 100)
%                             app.direction = app.direction - 2;
%                         end
%                     end
%                 case 'e'                        % E brake
%                     app.EButton.Value = 1;
%                     if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
%                         app.input_force = 150;
%                     end
%                 case 'q'                        % Pulls the car straight
%                     app.QButton.Value = 1;
%                     if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
%                         app.direction = 150;
%                     end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.now = tic;
            
            app.flag_circle = 0;
            app.flag_straight = 0;
            app.flag_selection = 0;
            app.forward_flag = 0;
            app.WButton.Value = 1;
            app.feed_flag = 0;
            app.flag_stop = 0;
            
            app.waypoint = app.waypoint + 1;
            app.y_waypoint_1 = app.WaypointYcoordinateEditField.Value;
            app.x_waypoint_1 = app.WaypointXcoordinateEditField.Value;
            plot(app.UIAxes, app.WaypointXcoordinateEditField.Value, app.WaypointYcoordinateEditField.Value, '.' ,'MarkerSize', 15, 'color', 'g')
            
            route_parameters(app);
            check_waypoint_field(app);
        end

        % Button pushed function: ShowCarButton
        function ShowCarButtonPushed(app, event)
            %
            %             app.hand = plot(app.kitt,app.UIAxes);
            %
            %             app.showcar = 1;
            %
            %             app.kitt = EPOCommunications('close');
            %             dis = sqrt((app.kitt.x(1) - app.x_waypoint_1)^2 + (app.kitt.x(2) - app.y_waypoint_1)^2);
            %             app.Finaldistance1EditField.Value = dis;
            %             app.startx = app.kitt.x(1);
            %             app.starty = app.kitt.x(2);
            %             app.start_angle = atan2(app.kitt.d(2),app.kitt.d(1));
            %
            %             initEPOcomm(app);
            %
            %             app.y_waypoint_1 = app.WaypointYcoordinateEditField.Value;
            %             app.x_waypoint_1 = app.WaypointXcoordinateEditField.Value;
            %
            %             route_parameters(app);
            %             check_waypoint_field(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Visible = 'off';
            app.WButton.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.WaypointXcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointXcoordinateEditFieldLabel.Text = 'Waypoint X-coordinate';
            app.WaypointXcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointYcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointYcoordinateEditFieldLabel.Text = 'Waypoint Y-coordinate';
            app.WaypointYcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.UIFigure, 'state');
            app.BButton.Text = 'B';
            app.Way2_xEditFieldLabel = uilabel(app.UIFigure);
            app.Way2_xEditFieldLabel.Text = 'Way 2_x';
            app.Way2_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.Way2_yEditFieldLabel = uilabel(app.UIFigure);
            app.Way2_yEditFieldLabel.Text = 'Way 2_y';
            app.Way2_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.Finaldistance1EditFieldLabel = uilabel(app.UIFigure);
            app.Finaldistance1EditFieldLabel.Text = 'Final distance 1';
            app.Finaldistance1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Finaldistance2EditFieldLabel = uilabel(app.UIFigure);
            app.Finaldistance2EditFieldLabel.Text = 'Final distance 2';
            app.Finaldistance2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowCarButton = uibutton(app.UIFigure, 'push');
            app.ShowCarButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCarButtonPushed, true);
            app.ShowCarButton.Visible = 'off';
            app.ShowCarButton.Text = 'Show Car';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_challange1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end