classdef GUI_V11_EPOcomm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AButton                       matlab.ui.control.StateButton
        SButton                       matlab.ui.control.StateButton
        DButton                       matlab.ui.control.StateButton
        WButton                       matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
        WaypointXcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointXcoordinateEditField  matlab.ui.control.NumericEditField
        WaypointYcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointYcoordinateEditField  matlab.ui.control.NumericEditField
        PlotButton                    matlab.ui.control.Button
        QButton                       matlab.ui.control.StateButton
        EButton                       matlab.ui.control.StateButton
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        x_position = 100;            % The starting position of the dot on x axis
        y_position = 1;            % The starting position of the dot on y axis
        x_front = 100;
        y_front = 153;
        
        x_mid = 0;
        y_mid = 0;
        
        x_line = 0;
        y_line = 0;
        x_line_2 = 0;
        y_line_2 = 0;
        
        x_box = [0, 600, 600, 0, 0];  % The outside of the arena
        y_box = [0, 0, 600, 600, 0];  % The outside of the arena
        
        x_waypoint_1=0;
        y_waypoint_1=0;
        x_waypoint_2=0;
        y_waypoint_2=0;
        waypoint_1_position=0;
        R=69.35;
        CircleLeft = [0 0];
        CircleRight = [0 0];
        xp1; yp1; xp2; yp2;
        angle_difference=0;
        car_waypoint_angle=0;
        facing_mod = 0;
        facing_c = 0;
        
        crash = 0;          % Initialize crash as 0
        
        input_force = 150;  % Force pressed on trigger
        force = 0;          % accelaration force
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        deadspeed = 0;      % speed of car
        facing = pi/2;      % Direction the car is facing in rad 0 = x
        
        flag_straight = 0;
        flag_circle = 0;
        forward_flag = 0;
        flag_selection = 0;
        feed_flag = 0;
        
        s1; s2; s3; s4; s5; s6; s7; s8; s9; s10; s11; s12; s13; s14;
        m = 5.6;
        b = 5;
        c = 0.1;
        x;
        h;
        t;
        tt = 0.1;  % Δt = 0.1 so loop period is set to 0.1 seconds
        now;
        Loop;
        x_distance; y_distance; distance;
        waypoint = 0;
        
        % new variables
        car_length = 33.5;
        car_outline = 0;
        car_model = 0;
        steering_radius = 999999;
        car_rotation = 0;
        turning_angle = 0;              % car angle in degrees (USE THIS PARAMETER FOR CALCULATIONS ETC)
        dif_angle = 0;
        
        S_left = 0;
        S_right = 0;
        distance_S_left = 9999;
        distance_S_right = 9999;
        distance_circle = 0;
        
        
        car_model_circle;
        front_line;
        back_line;
        circle_r;
        circle_l;
        car_model_plot;
        
        hand;
        hw;
        hs;
        kitt;
        
        estimated_x_position = 100;
        estimated_y_position = 1;
        old_estimated_x_position = 100;
        old_estimated_y_position = 1;
        estimated_speed = 0;
        estimated_facing = pi/2;
        estimated_position_difference = 0;
        estimated_position_difference_reference = 0;
        estimated_angle_difference = 0;
        estimated_angle_difference_reference = 0;
        tuning_flag = 0;
        
        prev_position = [100 1; 100 1; 100 1; 100 1; 100 1; 100 1; 100 1; 100 1; 100 1; 100 1];
    end
    
    methods (Access = private)
        function tracking(app) % purpose is to determine the speed and direction of ze car.
            app.old_estimated_x_position = app.estimated_x_position;
            app.old_estimated_y_position = app.estimated_y_position;
            app.estimated_x_position = app.kitt.position(1);
            app.estimated_y_position = app.kitt.position(2);
            
            dx = app.old_estimated_x_position - app.estimated_x_position;
            dy = app.old_estimated_y_position - app.estimated_y_position;
            
            %calculating new estimated facing
            estimated_direction = atan2(dy,dx);
            while estimated_direction < 0
                estimated_direction = estimated_direction + 2*pi;
            end
            
            if app.direction == 150
                app.estimated_facing = app.estimated_facing + (estimated_direction - app.estimated_facing)/2;
            else
                app.estimated_facing = app.estimated_facing + (estimated_direction - app.estimated_facing)*2;
            end
            
            %calculate new estimated speed
            if app.steering_radius == 0
                app.estimated_speed = sqrt((dx/100)^2 + (dy/100)^2) * app.tt; % conversion to SI  !!!
            else
                app.estimated_speed = app.steering_radius * 2 * asin(sqrt((dx/100)^2 + (dy/100)^2) / (2*app.steering_radius)) * app.tt; % conversion to SI  !!!
            end
            
%             %calculate estimated speed based on old and new position
%             dx_speed = app.estimated_x_position - app.old_estimated_x_position;
%             dy_speed = app.estimated_y_position - app.old_estimated_y_position;
%             
%             app.estimated_speed = sqrt((dx_speed/100)^2+(dy_speed/100)^2)/app.tt;
%             
%             %calculate estimated error direction
%             dx = app.x_position-app.estimated_x_position ;
%             dy = app.y_position-app.estimated_y_position;
%             app.error_direction = atan2(dy,dx);
%             
%             %calculate estimated position difference with estimated
%             %position and target position
%             app.estimated_position_difference = sqrt((app.estimated_x_position-app.x_position)^2 + (app.estimated_y_position-app.y_position)^2);
%             
%             
%             %When position difference is large enough, steer the car by
%             %double the angle differene to get on the target track, but
%             %only when on a straight line. Start tuning trace with
%             %position difference is 5 cm, stop when very small
%             %difference
%             
%             app.estimated_angle_difference = app.facing_mod - app.estimated_facing;
%             app.estimated_angle_difference = sign(app.estimated_angle_difference)*mod(app.estimated_angle_difference, pi);
%             
 
        end
        
        function feedback_control(app)
            % saving last positions
            for n = 1:9 
                app.prev_position(n,:) = app.prev_position(n+1,:);
            end
            app.prev_position(10,:) = [app.estimated_x_position app.estimated_y_position];
            
            % averaging last positions with weighted average
            a = [0 0]; 
            for n = 1:10
                a = a + (app.prev_position(n,:) + [100*abs(app.speed)*app.tt*(10-n)*cos(app.facing)  100*abs(app.speed)*app.tt*(10-n)*sin(app.facing)]) * n;
            end
            a = a/550;
            
            % correcting position 
            % this is now a box, change to circle
            if abs(app.x_position - a(1)) > 10 
                app.x_position = a(1);
            end
            if abs(app.y_position - a(2)) > 10
                app.y_position = a(2);
            end
            
            %correcting facing
            if abs(app.facing - app.estimated_facing) > 0.01
                app.facing = app.estimated_facing;
            end
        end
        
        function two_pi_normer(app)
            while app.facing_c >= 2*pi
                app.facing_c = app.facing_c - 2*pi;
            end
            while app.facing_c < 0
                app.facing_c = app.facing_c + 2*pi;
            end
        end
        function min_pi_to_pi_normer(app)
            while app.facing_c >= pi
                app.facing_c = app.facing_c - 2*pi;
            end
            while app.facing_c < -pi
                app.facing_c = app.facing_c + 2*pi;
            end
        end
        function route_parameters(app) %only called on plot button
            % circle center to waypoint
            app.S_left = [app.WaypointXcoordinateEditField.Value-app.CircleLeft(1) app.WaypointYcoordinateEditField.Value-app.CircleLeft(2)];
            app.S_right = [app.WaypointXcoordinateEditField.Value-app.CircleRight(1) app.WaypointYcoordinateEditField.Value-app.CircleRight(2)];
            
            % magnitude of Sdistance_circle_left
            app.distance_S_left = norm(app.S_left);
            app.distance_S_right = norm(app.S_right);
        end
        
        function circle_distance(app)
            % angles
            a_left = acos(app.R/app.distance_S_left);
            a_right = acos(app.R/app.distance_S_right);
            
            b_left = atan2(app.S_left(2),app.S_left(1));
            b_right = atan2(app.S_right(2),app.S_right(1));
            
            % setting facing_c for c1
            app.facing_c = app.facing + pi/2;
            two_pi_normer(app);
            if b_right - a_right < 0.1 && app.WaypointXcoordinateEditField.Value > app.x_position % correction for b-a < 0, with margin to prevent weird behavior in the case of an overshoot.
                min_pi_to_pi_normer(app);
                c_1 = (app.facing_c) - (b_right - a_right);
            elseif b_right >= 0
                c_1 = (app.facing_c) - (b_right - a_right);
            else
                c_1 = (app.facing_c) - 2*pi - (b_right - a_right);
            end
            
            % setting facing_c for c2
            app.facing_c = app.facing - pi/2;
            if b_left >= pi/2 || b_left <= -pi/2
                two_pi_normer(app);
                if b_left >= 0
                    c_2 = (b_left + a_left) - (app.facing_c);
                else
                    c_2 = 2*pi + (b_left + a_left) - (app.facing_c);
                end
            else
                min_pi_to_pi_normer(app);
                c_2 = (b_left + a_left) - (app.facing_c);
            end
            
            % setting facing_c for c3
            app.facing_c = app.facing - pi/2;
            if b_left >=0
                min_pi_to_pi_normer(app);
                c_3 = (b_left - a_left) - (app.facing_c);
            else
                two_pi_normer(app);
                c_3 = 2*pi + (b_left - a_left) - (app.facing_c);
            end
            
            % setting facing_c for c4
            app.facing_c = app.facing - pi/2;
            if b_left >= 0
                two_pi_normer(app);
            else
                min_pi_to_pi_normer(app);
            end
            c_4 = (app.facing_c) - (b_left + a_left);
            
            % setting facing_c for c5
            app.facing_c = app.facing + pi/2;
            if b_right - a_right <= 0
                two_pi_normer(app);
                c_5 = 2*pi - (app.facing_c) + (b_right - a_right);
            else
                min_pi_to_pi_normer(app);
                c_5 = (b_right - a_right) - (app.facing_c);
            end
            
            % setting facing_c for c6
            app.facing_c = app.facing + pi/2;
            if b_right >= 0
                two_pi_normer(app);
            else
                min_pi_to_pi_normer(app);
            end
            c_6 = (app.facing_c) - (a_right + b_right);
            
            
            % distance
            if (app.waypoint_1_position == 1)
                app.distance_circle = c_1*app.R;
            elseif (app.waypoint_1_position == 2)
                app.distance_circle = c_2*app.R;
            elseif (app.waypoint_1_position == 3)
                app.distance_circle = c_3*app.R;
            elseif (app.waypoint_1_position == 4)
                app.distance_circle = c_4*app.R;
            elseif (app.waypoint_1_position == 5)
                app.distance_circle = c_5*app.R;
            elseif (app.waypoint_1_position == 6)
                app.distance_circle = c_6*app.R;
            end
        end
        
        function circle_data(app)
            %Determine center points of the right and left circle
            app.CircleLeft = [app.x_position-app.R*sin(app.facing) app.y_position+app.R*cos(app.facing)];
            app.CircleRight = [app.x_position+app.R*sin(app.facing) app.y_position-app.R*cos(app.facing)];
            %             Determine point on circle for plotting
            p=2*pi();
            ang = linspace(0, p);
            app.xp1=app.x_position-app.R*sin(app.facing)+app.R*cos(ang);
            app.yp1=app.y_position+app.R*cos(app.facing)+app.R*sin(ang);
            app.xp2=app.x_position+app.R*sin(app.facing)-app.R*cos(ang);
            app.yp2=app.y_position-app.R*cos(app.facing)-app.R*sin(ang);
            %             circ1=[app.xp1;app.yp1];
            %             circ2=[app.xp2;app.yp2];
        end
        
        function check_waypoint_field(app) %only called on plot button


            %Determine the relative angle from the rear point of the car to
            %the waypoint and place it in a range 0-2pi
            app.car_waypoint_angle=atan2((app.y_waypoint_1-app.y_position), (app.x_waypoint_1-app.x_position));
            if(app.car_waypoint_angle <0)
                app.car_waypoint_angle =2*pi+app.car_waypoint_angle;
            end
            
            %take the modulo of car facing angle and place it in a range 0-2pi
            app.facing_mod=mod(app.facing, 2*pi);
            app.angle_difference = (app.car_waypoint_angle-app.facing_mod);
            if(app.angle_difference <0)
                app.angle_difference =2*pi+app.angle_difference;
            end
            
            %%This code determines where the waypoint is with respect to
            %%the car:
            % 0: error
            % 1: inside the left circle
            % 2: inside the right circle
            % 3: upper left quadrant
            % 4: lower left quadrant
            % 5: lower richt quadrant
            % 6: upper richt quadrant
            % 7: straight ahead
            % 8: exactly left
            % 9: straight below
            % 10: exactly right
            if app.flag_selection == 0 && (app.WaypointXcoordinateEditField.Value ~= 0 || app.WaypointYcoordinateEditField.Value ~= 0)
                if app.distance_S_left <= app.R
                    app.waypoint_1_position = 1;
                elseif app.distance_S_right <= app.R
                    app.waypoint_1_position = 2;
                elseif app.angle_difference>0 && app.angle_difference<0.5*pi
                    app.waypoint_1_position = 3;
                elseif app.angle_difference>0.5*pi && app.angle_difference<1*pi
                    app.waypoint_1_position = 4;
                elseif app.angle_difference>1*pi && app.angle_difference<1.5*pi
                    app.waypoint_1_position = 5;
                elseif app.angle_difference>1.5*pi && app.angle_difference<2*pi
                    app.waypoint_1_position = 6;
                elseif app.angle_difference == 0
                    app.waypoint_1_position = 7;
                elseif app.angle_difference == 0.5*pi
                    app.waypoint_1_position = 3; % from 8 --> 3
                elseif app.angle_difference == 1*pi
                    app.waypoint_1_position = 9;
                elseif app.angle_difference == 1.5*pi
                    app.waypoint_1_position = 6; % from 10 --> 6
                end
                app.flag_selection = 1;
            elseif app.distance < 15 && app.speed < 0.1
                app.flag_selection = 0;
            end
        end
        
        function bangbangcontrol_straightline(app)
            
            time_delay = 2*app.tt;
            
            if app.speed >= 0
                total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
                total_force = app.force - app.b*app.estimated_speed - app.c*app.speed^2;
            else
                total_force_a = -abs(app.force) - app.b*app.speed + app.c*app.speed^2;
                total_force = app.force - app.b*app.speed + app.c*app.speed^2;
            end
            projected_distance = app.distance - 100*abs(app.speed)*time_delay - 100*abs(total_force_a)*time_delay^2/(2*app.m); %in CM!!!!!!
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            braking_velocity = @(t) exp(-(100/112)*t)*(2+abs(projected_speed))-2;
            upperlim = -112*log(10/(10+5*abs(projected_speed)))/100;
            braking_distance = integral(braking_velocity,0,upperlim);
            
            if (100*braking_distance >= projected_distance || app.flag_straight == 1)
                if abs(app.speed) <= 0.5 || app.distance <= 2
                    app.input_force = 150;  % "coasting" state when nearing endpoint
                    app.flag_straight = 2;
                else
                    if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9 || app.forward_flag == 1)
                        app.input_force = 165;  % "hard brake" state when nearing endpoint
                        app.forward_flag = 1;
                    else
                        app.input_force = 135;
                        app.forward_flag = 0;
                    end
                    app.flag_straight = 1;
                end
            elseif app.flag_straight == 0
                if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5 || app.waypoint_1_position == 1 || app.waypoint_1_position == 2 || app.waypoint_1_position == 9 || app.forward_flag == 1)
                    app.input_force = 135;      % "go" state
                    app.forward_flag = 1;
                else
                    app.input_force = 165;
                    app.forward_flag = 0;
                end
                
                app.direction = 150;
                app.flag_straight = 0;
            else
                app.input_force = 150;      % Idle state
                app.flag_straight = 2;
            end
        end
        
        function bangbangcontrol_circle(app)
            
            time_delay = 2*app.tt;
            
            if app.speed >= 0
                total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
                total_force = app.force - app.b*app.speed - app.c*app.speed^2;
            else
                total_force_a = -abs(app.force) - app.b*app.speed + app.c*app.speed^2;
                total_force = app.force - app.b*app.speed + app.c*app.speed^2;
            end
            
            projected_distance = abs(app.distance_circle) - 100*abs(app.speed)*time_delay - 100*abs(total_force_a)*time_delay^2/(2*app.m); %in CM!!!!!!
            
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            braking_velocity = @(t) exp(-(100/112)*t)*(2+abs(projected_speed))-2;
            upperlim = -112*log(10/(10+5*abs(projected_speed)))/100;
            braking_distance = integral(braking_velocity,0,upperlim);
            
            if (100*braking_distance >= projected_distance || app.flag_circle == 1)
                if abs(app.estimated_speed) <= 0.1
                    app.input_force = 150;  % "coasting" state when nearing endpoint
                    app.flag_circle = 2;
                else
                    if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5)
                        app.input_force = 165;
                    else
                        app.input_force = 135;
                    end
                    
                    app.flag_circle = 1;
                end
            elseif app.flag_circle == 0
                if (app.waypoint_1_position == 4 || app.waypoint_1_position == 5)
                    app.input_force = 149;
                else
                    app.input_force = 151;
                end
                
                app.flag_circle = 0;
                
                if (app.waypoint_1_position == 2 || app.waypoint_1_position == 3 || app.waypoint_1_position == 4)
                    app.direction = 200;
                elseif (app.waypoint_1_position == 1 || app.waypoint_1_position == 5 || app.waypoint_1_position == 6)
                    app.direction = 100;
                end
            else
                app.input_force = 150;      % Idle state
                app.flag_circle = 2;
                app.direction = 150;
            end
        end
        
        function ploteverything(app)
            
            % Plot car front and back location, lines and arena
            
            %             delete(app.car_model_circle);
            delete(app.front_line);
            delete(app.back_line);
            %             app.car_model_circle  = plot(app.UIAxes,  app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            app.front_line  = plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--');
            app.back_line = plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
            %to plot the cirles
            
            delete(app.circle_r);
            delete(app.circle_l);
            app.circle_r = plot(app.UIAxes, app.xp1, app.yp1, "b");
            app.circle_l = plot(app.UIAxes, app.xp2, app.yp2, "b");


            % Plot the text
            app.h(1) = text(app.UIAxes,-90,490,app.s1);
            app.h(2) = text(app.UIAxes,-90,470,app.s2);
            app.h(3) = text(app.UIAxes,-90,450,app.s3);
            app.h(4) = text(app.UIAxes,-90,430,app.s4);
            app.h(5) = text(app.UIAxes,-90,410,app.s5);
            app.h(6) = text(app.UIAxes,-90,390,app.s6);
            app.h(7) = text(app.UIAxes,-90,370,app.s7);
            app.h(8) = text(app.UIAxes,-90,350,app.s8);
            app.h(9) = text(app.UIAxes,-90,330,app.s9);
            app.h(10) = text(app.UIAxes,-90,310,app.s10);
            app.h(11) = text(app.UIAxes, -90, 290, app.s11);
            app.h(12) = text(app.UIAxes, -90, 270, app.s12);
            app.h(13) = text(app.UIAxes, -90, 230, app.s13);
            app.h(14) = text(app.UIAxes, -90, 200, app.s14);
            %             app.h(15) = text(app.UIAxes, -90, 210, app.s15);
            %             app.h(16) = text(app.UIAxes, -90, 190, app.s16);
            %             app.h(17) = text(app.UIAxes, -90, 270, app.s17);
            
            % plot the waypoint
            %             if (app.waypoint >= 1)
            %                 plot(app.UIAxes, app.x_waypoint_1,app.y_waypoint_1, '.' ,'MarkerSize', 15, 'color', 'g')
            %             end
            % show car
            % query car status (kitt automatic updates as time progresses)
            x_pos = app.kitt.position;
            
            
            plot(app.UIAxes,x_pos(1),x_pos(2),'bo');		% draw car position as 'o'
            delete(app.hand)				% remove old outline of car
            app.hand = plot(app.kitt,app.UIAxes);		% draw outline of the car
            
            
            % plot the waypoint
            if (app.waypoint >= 1)
                plot(app.UIAxes, app.x_waypoint_1,app.y_waypoint_1, '.' ,'MarkerSize', 15, 'color', 'g')
            end
            %             % update plot of waypoints (ERROR not efficient to do this within the loop)
            %             delete(app.hw);
            %             app.hw = plot(app.kitt.Waypoints,app.UIAxes);
            
            % update plot of obstacles (ERROR not efficient to do this within the loop)
            delete(app.hs);
            app.hs = plot(app.kitt.Sensors,app.UIAxes);	% plot obstacles and arena bounding box
            
            app.car_outline = polyshape([app.x_position-21 app.x_position-21 app.x_position+21 app.x_position+21],[app.y_position-9.15 app.y_position+app.car_length+9.15 app.y_position+app.car_length+9.15 app.y_position-9.15]);
            app.car_rotation = (app.facing - pi/2)*180/pi;
            app.car_model = rotate(app.car_outline, app.car_rotation, [app.x_position app.y_position]);
            delete(app.car_model_plot);
            app.car_model_plot = plot(app.UIAxes, app.car_model);
            app.car_model_plot.FaceColor = 'b';
            
            drawnow limitrate nocallbacks;	% flush plot buffer
        end
        
        function newstuff(app)
            app.x_front = cos(app.facing)*app.car_length + app.x_position;
            app.y_front = sin(app.facing)*app.car_length + app.y_position;
            
            app.x_mid = cos(app.facing)*app.car_length/2 + app.x_position;
            app.y_mid = sin(app.facing)*app.car_length/2 + app.y_position;
            
            app.x_line = app.x_front + (1000*cos(app.facing));
            app.y_line = app.y_front + (1000*sin(app.facing));
            app.x_line_2 = app.x_front - (1000*cos(app.facing));
            app.y_line_2 = app.y_front - (1000*sin(app.facing));
            
        end


        function collision(app)
            % Checks if the car is outside of the arena
            if (app.x_position >= 600 || app.x_front >= 600 ) || (app.x_position <= 0 || app.x_front <= 0) || (app.y_position <= 0 || app.y_front <= 0) || (app.y_position >= 600 || app.y_front >= 600)
                app.crash = 1;      % If crashed set crash to 1
                app.speed = 0;
                stop(app.LoopFcn);     % If crashed stop the loop
                % Plot the crash location
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.x_box, app.y_box, 'r-');
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
                
                app.h(1) = text(app.UIAxes,-90,490,app.s1);
                app.h(2) = text(app.UIAxes,-90,470,app.s2);
                app.h(3) = text(app.UIAxes,-90,450,app.s3);
                app.h(4) = text(app.UIAxes,-90,430,app.s4);
                app.h(5) = text(app.UIAxes,-90,410,app.s5);
                app.h(6) = text(app.UIAxes,-90,390,app.s6);
                app.h(7) = text(app.UIAxes,-90,370,app.s7);
                app.h(8) = text(app.UIAxes,-90,350,app.s8);
                app.h(9) = text(app.UIAxes,-90,330,app.s9);
                app.h(10) = text(app.UIAxes,-90,310,app.s10);
                app.h(11) = text(app.UIAxes, -90, 290, app.s11);
                app.h(12) = text(app.UIAxes, -90, 270, app.s12);
                app.h(13) = text(app.UIAxes, -90, 250, app.s13);
                app.h(14) = text(app.UIAxes, -90, 230, app.s14);
            end
        end
        
        function velocity(app)
            % Speed is calculated in m/s now, so *100 is added on the display in the figure
            app.force = (app.input_force - 150)*2/3;
            if app.speed >= 0
                app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m - app.c*app.speed^2*app.tt/app.m;
            else
                app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m + app.c*app.speed^2*app.tt/app.m;
            end
            if abs(app.speed) < 0.001
                app.deadspeed = 0;              % If car goes VERY slow set speed to 0 (deadzone)
            else
                app.deadspeed = app.speed;
            end
        end
        
        function waypointdistance(app)
            % Calculate the distance to the waypoint
            % Only supports 1 waypoint as of now
            app.x_distance = abs(app.WaypointXcoordinateEditField.Value - app.x_mid);
            app.y_distance = abs(app.WaypointYcoordinateEditField.Value - app.y_mid);
            
            app.distance = sqrt(app.x_distance^2 + app.y_distance^2);
        end
        
        function initEPOcomm(app)
            % initializations
            EPOCommunications('init','X[100;17.75]');	% initial position of car
            EPOCommunications('init','D[0;1]');	% initial direction of car
            
            % define positions of microphones
            P = [
                0   600     0   600;
                0     0   600   600;
                30   30    30    30];
            pp = mat2str(P);
            EPOCommunications('init',['P' pp]);% initialize positions of mics
            EPOCommunications('init','J20000');	% set sample rate of mics
            EPOCommunications('init','N2000');	% set number of samples to return
            
            
            % define arena boundary
            B1 = [
                -50 650 650 -50;
                -50 -50 650 650];	% outer arena boundary (outside bounding box)
            NAN = [NaN; NaN];	% spacer between objects
            B2 = [
                -21 621 621 -21;
                -21 -21 621 621];	% inner arena boundary (hole since it overlaps B1)
            bb = mat2str([B1 NAN B2]);
            EPOCommunications('init',['O' bb]);	% initialize arena boundary
            
            %             % define obstacle position (polygons)
            %             O1 = [
            %                 200 200 260 230;
            %                 200 240 240 200];	% first obstacle
            %             O2 = [
            %                 350 380 410 410;
            %                 200 240 240 200];	% second obstacle
            %             oo = mat2str([O1 NAN O2]);
            %             EPOCommunications('init',['O' oo]);	% initialize obstacles
            %
            %             % define two waypoints
            %             W = [
            %                 100   400;
            %                 100   400];
            %             ww = mat2str(W);
            %             EPOCommunications('init',['W' ww]);	% initialize waypoints
            
            
            % create kitt!
            app.kitt = EPOCommunications('open','P');	% create kitt (P = public, with access
       					% to position, orientation, velocity)
        end
        
        function LoopFcn(app,~,~)
            
            hold(app.UIAxes,'on')   % Keeps old dot locations and arena
            
            collision(app);         % Checks every 0.1 seconds if the car has gone outside the arena
            circle_data(app);
            if (app.waypoint >= 1)
                waypointdistance(app);  % Checks every 0.1 seconds the distance to the waypoint if a waypoint is present
                circle_distance(app);
            end
            if (app.WButton.Value == 1 )
                tracking(app);
                if app.feed_flag >= 10
                    feedback_control(app);
                else
                    app.feed_flag = app.feed_flag + 1;
                end
            end
            
            
            app.t = toc(app.now);
            app.s1 = sprintf('force: %g N', app.input_force);   %kitt.force
            app.s2 = sprintf('velocity: %g cm/s', app.speed*100);   %kitt.velocity
            app.s3 = sprintf('angle: %g', app.direction);       %kitt.angle
            app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            app.s6 = sprintf('time: %0.2f s',app.t);            %time
            app.s7 = sprintf('crash: %g', app.crash);           %carchrash(kitt);
            app.s8 = sprintf('zaps: ');                         %kitt.numzaps;
            app.s9 = sprintf('tuning flag: %g cm', app.tuning_flag);
            app.s10 = sprintf('Waypoint location: %g', app.waypoint_1_position);
            app.s11 = sprintf('angle difference: %g', app.angle_difference/(2*pi)*360);
            app.s12 = sprintf('car waypoint angle: %g', app.car_waypoint_angle/(2*pi)*360);
            app.s13 = sprintf('facing: %g', app.facing);
            app.s14 = sprintf('est facing: %g', app.estimated_facing);
            delete(app.h);  %delete text before writing it again
            
            % Determine new position based on force and angle
            if app.direction == 150
                app.steering_radius = 0;
            else
                app.steering_radius = 0.335/tan((app.direction-150)*0.45/50);
            end
            if app.steering_radius == 0
                app.x_position = app.x_position + 100*app.speed*app.tt * cos(app.facing);
                app.y_position = app.y_position + 100*app.speed*app.tt * sin(app.facing);
                app.facing = app.facing;
            else
                app.x_position = app.x_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * cos(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.y_position = app.y_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * sin(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.facing = app.facing + app.speed*app.tt/app.steering_radius;
            end
            app.facing = mod(app.facing, 2*pi);
            app.aEditField.Value = app.facing;
            newstuff(app);
            
            % If statement to prevent plotting another dot outside the arena
            if (app.crash == 0)
                ploteverything(app);
            end
            
            velocity(app);
            
            if (app.WButton.Value == 1 )
                if (app.flag_circle == 2)
                    bangbangcontrol_straightline(app);
                else
                    bangbangcontrol_circle(app);
                    
                end
            end
            
            
            app.kitt.force = app.input_force;
            app.kitt.angle = app.direction;
            
            % Clear the WASD control keys from their input
            %app.WButton.Value = 0;
            app.AButton.Value = 0;
            app.SButton.Value = 0;
            app.DButton.Value = 0;
            app.EButton.Value = 0;
            app.QButton.Value = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialize EPO comm
            addpath carmodel -begin
            initEPOcomm(app);
            
            % Gives the right axis' on startup
            app.UIAxes.YLim = [-100 700];
            app.UIAxes.XLim = [-100 700];
            
            % Plot everything
            % Plot car front and back location, lines and arena
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--');
            plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
            plot(app.UIAxes,[0 600 600 0 0],[0 0 600 600 0],'--');
            plot(app.UIAxes,  app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            %             % Plot the mics  % managed by kitt
            %             plot(app.UIAxes, 600, 600, 'x', 'color', 'r', 'LineWidth', 2)
            %             plot(app.UIAxes, 600, 0, 'x', 'color', 'r', 'LineWidth', 3)
            %             plot(app.UIAxes, 0, 600, 'x', 'color', 'r', 'LineWidth', 3)
            %             plot(app.UIAxes, 0, 0, 'x', 'color', 'r', 'LineWidth', 3)
            %             plot(app.UIAxes, 300, 0, 'x', 'color', 'r', 'LineWidth', 3)
            
            % show initial position of kitt car
            app.hand = plot(app.kitt,app.UIAxes);		% draw outline of the car
            app.hs = plot(app.kitt.Sensors,app.UIAxes);	% plot obstacles and arena bounding box
            plot(app.kitt.Micarray,app.UIAxes);		% plot mics as "x"
            app.hw = plot(app.kitt.Waypoints,app.UIAxes);	% plot waypoints as "o"
            
            % Prints the initial texts in the plot
            app.now = tic;
            app.s1 = sprintf('force: 0 N');
            app.s2 = sprintf('velocity: 0 m/s');
            app.s3 = sprintf('angle: 0 ');
            app.s4 = sprintf('distL: %g  cm', app.x_position);
            app.s5 = sprintf('distR: %g cm', app.y_position);
            app.s6 = sprintf('time: 0 s');
            
            app.h(1) = text(app.UIAxes,5,490,app.s1);
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            
            drawnow limitrate nocallbacks;	% flush plot buffer
            % Create timer object
            app.Loop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt, ...                % Period of the function
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LoopFcn);           % Specify callback function
            
            % Start the loop
            start(app.Loop);
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'a'
                    app.AButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.direction < 200)
                            app.direction = app.direction + 2;
                        end
                    end
                case 'w'
                    if (app.WButton.Value == 0)
                        app.WButton.Value = 1;
                    else
                        app.WButton.Value = 0;
                    end
                    %if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                    %    if (app.input_force < 165)
                    %        app.input_force = app.input_force + 1;
                    %    end
                    %end
                case 's'
                    app.SButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.input_force > 135)
                            app.input_force = app.input_force - 1;
                        end
                    end
                case 'd'
                    app.DButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.direction > 100)
                            app.direction = app.direction - 2;
                        end
                    end
                case 'e'                        % E brake
                    app.EButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        app.input_force = 150;
                    end
                case 'q'                        % Pulls the car straight
                    app.QButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        app.direction = 150;
                    end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.flag_circle = 0;
            app.flag_straight = 0;
            app.flag_selection = 0;
            app.forward_flag = 0;
            app.WButton.Value = 1;
            
            app.waypoint = app.waypoint + 1;
            app.y_waypoint_1 = app.WaypointYcoordinateEditField.Value;
            app.x_waypoint_1 = app.WaypointXcoordinateEditField.Value;
            plot(app.UIAxes, app.WaypointXcoordinateEditField.Value, app.WaypointYcoordinateEditField.Value, '.' ,'MarkerSize', 15, 'color', 'g')
            
            route_parameters(app);
            check_waypoint_field(app);
        end

        % Value changed function: WaypointXcoordinateEditField
        function WaypointXcoordinateEditFieldValueChanged(app, event)
            %             app.x_waypoint_1 = app.WaypointXcoordinateEditField.Value;
        end

        % Value changed function: WaypointYcoordinateEditField
        function WaypointYcoordinateEditFieldValueChanged(app, event)
            %             app.x_waypoint_2 = app.WaypointYcoordinateEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'state');
            app.DButton.Text = 'D';
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.WaypointXcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointXcoordinateEditFieldLabel.Text = 'Waypoint X-coordinate';
            app.WaypointXcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointXcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @WaypointXcoordinateEditFieldValueChanged, true);
            app.WaypointYcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointYcoordinateEditFieldLabel.Text = 'Waypoint Y-coordinate';
            app.WaypointYcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointYcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @WaypointYcoordinateEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.QButton = uibutton(app.UIFigure, 'state');
            app.QButton.Text = 'Q';
            app.EButton = uibutton(app.UIFigure, 'state');
            app.EButton.Text = 'E';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_V11_EPOcomm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end