classdef GUI_V5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AButton                       matlab.ui.control.StateButton
        SButton                       matlab.ui.control.StateButton
        DButton                       matlab.ui.control.StateButton
        WButton                       matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
        WaypointXcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointXcoordinateEditField  matlab.ui.control.NumericEditField
        WaypointYcoordinateEditFieldLabel  matlab.ui.control.Label
        WaypointYcoordinateEditField  matlab.ui.control.NumericEditField
        PlotButton                    matlab.ui.control.Button
        QButton                       matlab.ui.control.StateButton
        EButton                       matlab.ui.control.StateButton
        facingEditFieldLabel          matlab.ui.control.Label
        facingEditField               matlab.ui.control.NumericEditField
        difangleEditFieldLabel        matlab.ui.control.Label
        difangleEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        x_position = 100            % The starting position of the dot on x axis
        y_position = 1            % The starting position of the dot on y axis
        
        x_front = 100;
        y_front = 153;
        
        x_mid = 0;
        y_mid = 0;
        
        x_line = 0;
        y_line = 0;
        x_line_2 = 0;
        y_line_2 = 0;
                    
        x_box = [0, 600, 600, 0, 0];  % The outside of the arena
        y_box = [0, 0, 600, 600, 0];  % The outside of the arena
        
        crash = 0;          % Initialize crash as 0
        
        input_force = 150;  % Force pressed on trigger
        force = 0;          % accelaration force
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        facing = pi/2;      % Direction the car is facing in rad 0 = x
        
        s1; s2; s3; s4; s5; s6; s7; s8; s9;
        m = 5.6;
        b = 5;
        c = 0.1;
        x;
        h;
        t;
        tt = 0.1;  % Δt = 0.1 so loop period is set to 0.1 seconds
        now;  
        Loop;
        x_distance; y_distance; distance;
        waypoint = 0;
        
        % new variables
        car_length = 52;
        car_outline = 0;
        car_model = 0;
        steering_radius = 999999;
        car_rotation = 0;               % car angle in degrees (ONLY USED FOR ROTATING CAR)
        turning_angle = 0;              % car angle in degrees (USE THIS PARAMETER FOR CALCULATIONS ETC)
        
        dif_angle = 0;
    end
    
    methods (Access = private)
        
        function ploteverything(app)
            
            % Plot car front and back location, lines and arena
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--');
            plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
            
            % Plot the mics
            plot(app.UIAxes, 600, 600, 'x', 'color', 'r', 'LineWidth', 2)
            plot(app.UIAxes, 600, 0, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 0, 600, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 0, 0, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 300, 0, 'x', 'color', 'r', 'LineWidth', 3)
            
            % Plot the text
            app.h(1) = text(app.UIAxes,-90,490,app.s1);
            app.h(2) = text(app.UIAxes,-90,470,app.s2);
            app.h(3) = text(app.UIAxes,-90,450,app.s3);
            app.h(4) = text(app.UIAxes,-90,430,app.s4);
            app.h(5) = text(app.UIAxes,-90,410,app.s5);
            app.h(6) = text(app.UIAxes,-90,390,app.s6);
            app.h(7) = text(app.UIAxes,-90,370,app.s7);
            app.h(8) = text(app.UIAxes,-90,350,app.s8);
            app.h(9) = text(app.UIAxes,-90,330,app.s9);
            
            % plot the waypoint
            if (app.waypoint >= 1)
                plot(app.UIAxes, app.WaypointXcoordinateEditField.Value, app.WaypointYcoordinateEditField.Value, '.' ,'MarkerSize', 15, 'color', 'g')
            end
        end    
            
        function newstuff(app)            
            app.x_front = cos(app.facing)*app.car_length + app.x_position;
            app.y_front = sin(app.facing)*app.car_length + app.y_position;
            
            app.x_mid = cos(app.facing)*app.car_length/2 + app.x_position;
            app.y_mid = sin(app.facing)*app.car_length/2 + app.y_position;
            
            app.x_line = app.x_front + (1000*cos(app.facing));
            app.y_line = app.y_front + (1000*sin(app.facing));
            app.x_line_2 = app.x_front - (1000*cos(app.facing));
            app.y_line_2 = app.y_front - (1000*sin(app.facing));
        
        end    
        
        function collision(app)
            % Checks if the car is outside of the arena
            if (app.x_position >= 600 || app.x_front >= 600 ) || (app.x_position <= 0 || app.x_front <= 0) || (app.y_position <= 0 || app.y_front <= 0) || (app.y_position >= 600 || app.y_front >= 600)
                app.crash = 1;      % If crashed set crash to 1
                app.speed = 0;
                stop(app.Loop);     % If crashed stop the loop
                
                % Plot the crash location
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.x_box, app.y_box, 'r-');
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
                
                app.h(1) = text(app.UIAxes,5,490,app.s1);
                app.h(2) = text(app.UIAxes,5,470,app.s2);
                app.h(3) = text(app.UIAxes,5,450,app.s3);
                app.h(4) = text(app.UIAxes,5,430,app.s4);
                app.h(5) = text(app.UIAxes,5,410,app.s5);
                app.h(6) = text(app.UIAxes,5,390,app.s6);
                app.h(7) = text(app.UIAxes,5,370,app.s7);
                app.h(8) = text(app.UIAxes,5,350,app.s8);
                app.h(9) = text(app.UIAxes,5,330,app.s9);
            end
        end
        
        function velocity(app)
        % Speed is calculated in m/s now, so *100 is added on the display in the figure
            app.force = (app.input_force - 150)*2/3;
            app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m - app.c*app.speed^2*app.tt/app.m;
        end
    
        function waypointdistance(app)
            % Calculate the distance to the waypoint
                % Only supports 1 waypoint as of now
            app.x_distance = abs(app.WaypointXcoordinateEditField.Value - app.x_front);
            app.y_distance = abs(app.WaypointYcoordinateEditField.Value - app.y_front);
                
            app.distance = sqrt(app.x_distance^2 + app.y_distance^2);
        end
        
        function waypointdirection(app)
%             x_waypoint = 300;
%             y_waypoint = 300;
%             x_car = 600;
%             y_car = 301;
%             facing = pi;
%             forward = 1;
            
            x_dif = app.WaypointXcoordinateEditField.Value - app.x_position;
            y_dif = app.WaypointYcoordinateEditField.Value - app.y_position;
            
            if x_dif > 0 && y_dif > 0
                direction_waypoint = atan(y_dif/x_dif);
            elseif x_dif < 0 && y_dif > 0
                direction_waypoint = pi/2 - atan(y_dif/x_dif);
            elseif x_dif > 0 && y_dif < 0
                direction_waypoint = atan(y_dif/x_dif);
            elseif x_dif < 0 && y_dif < 0
                direction_waypoint = -pi/2 - atan(y_dif/x_dif);
            elseif x_dif == 0 && y_dif < 0
                direction_waypoint = pi;
            elseif x_dif == 0 && y_dif > 0
                direction_waypoint = 0;
            elseif x_dif < 0 && y_dif == 0
                direction_waypoint = -pi/2;
            elseif x_dif > 0 && y_dif == 0
                direction_waypoint = pi/2;
            else
                direction_waypoint = app.facing;
            end
% stuff = atan(x_dif/y_dif);
            while app.facing > pi || app.facing <= -pi
                if app.facing > pi
                    app.facing = app.facing - 2*pi;
                elseif app.facing <= -pi
                    app.facing = app.facing + 2*pi;
                end
            end
            if abs(direction_waypoint - app.facing) > pi
                if direction_waypoint > app.facing
                    app.facing = app.facing + 2*pi;
                elseif direction_waypoint < app.facing
                    direction_waypoint = direction_waypoint + 2*pi;
                end
            end
        
%             if abs(direction_waypoint - app.facing) < 0.02 && forward == 1
%                 f = 'stop turning';
%             elseif  abs(direction_waypoint - app.facing) > pi - 0.02 && forward == 0
%                 f = 'stop turning';
%             end
            app.dif_angle = direction_waypoint - app.facing;
            app.difangleEditField.Value = app.dif_angle*180/pi;
        end
    
        function LoopFcn(app,~,~)
            
            hold(app.UIAxes,'on')   % Keeps old dot locations and arena
           
            collision(app);         % Checks every 0.1 seconds if the car has gone outside the arena
            
            if (app.waypoint >= 1)
                waypointdistance(app);  % Checks every 0.1 seconds the distance to the waypoint if a waypoint is present
                waypointdirection(app);
            end

            app.t = toc(app.now);
            app.s1 = sprintf('force: %g N', app.input_force);   %kitt.force
            app.s2 = sprintf('velocity: %g cm/s', app.speed*100);   %kitt.velocity
            app.s3 = sprintf('angle: %g', app.direction);       %kitt.angle
            app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            app.s6 = sprintf('time: %0.2f s',app.t);            %time
            app.s7 = sprintf('crash: %g', app.crash);           %carchrash(kitt);
            app.s8 = sprintf('zaps: ');                         %kitt.numzaps;
            app.s9 = sprintf('dist. to wp: %g cm', app.distance);       
            
            delete(app.h);  %delete text before writing it again
          
            % Determine new position based on force and angle
            if app.direction == 150
                app.steering_radius = 0;
            else
                app.steering_radius = 0.335/tan((app.direction-150)*0.45/50);
            end
            if app.steering_radius == 0
                app.x_position = app.x_position + 100*app.speed*app.tt * cos(app.facing);
                app.y_position = app.y_position + 100*app.speed*app.tt * sin(app.facing);
                app.facing = app.facing;
            else
                app.x_position = app.x_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * cos(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.y_position = app.y_position + 100*2*app.steering_radius*sin(app.speed*app.tt/(2*app.steering_radius)) * sin(app.facing + app.speed*app.tt/(2*app.steering_radius));
                app.facing = app.facing + app.speed*app.tt/app.steering_radius;
            end
            
            newstuff(app);
            
            app.facingEditField.Value = app.facing;
            
            
            % If statement to prevent plotting another dot outside the arena
            if (app.crash == 0)
                cla(app.UIAxes);
                
                app.car_outline = polyshape([app.x_position-21 app.x_position-21 app.x_position+21 app.x_position+21],[app.y_position app.y_position+52 app.y_position+52 app.y_position]);
                app.car_rotation = (app.facing - pi/2)*180/pi;
                app.car_model = rotate(app.car_outline, app.car_rotation, [app.x_position app.y_position]);
                
                ploteverything(app);
                plot(app.UIAxes, app.car_model);
            end
            
            velocity(app);
            
            % Clear the WASD control keys from their input
            app.WButton.Value = 0;
            app.AButton.Value = 0;
            app.SButton.Value = 0;
            app.DButton.Value = 0;
            app.EButton.Value = 0;
            app.QButton.Value = 0;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Gives the right axis' on startup
            app.UIAxes.YLim = [-100 700];
            app.UIAxes.XLim = [-100 700];
            
            % Plot everything
            % Plot car front and back location, lines and arena
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_front, app.y_front,'bo', app.x_box, app.y_box, 'r-');
            plot(app.UIAxes, [app.x_front app.x_line],[app.y_front app.y_line], 'r--');
            plot(app.UIAxes, [app.x_position app.x_line_2],[app.y_position app.y_line_2], 'g--');
            
            % Plot the mics
            plot(app.UIAxes, 600, 600, 'x', 'color', 'r', 'LineWidth', 2)
            plot(app.UIAxes, 600, 0, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 0, 600, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 0, 0, 'x', 'color', 'r', 'LineWidth', 3)
            plot(app.UIAxes, 300, 0, 'x', 'color', 'r', 'LineWidth', 3)
              
            % Prints the initial texts in the plot
            app.now = tic;
            app.s1 = sprintf('force: 0 N');
            app.s2 = sprintf('velocity: 0 m/s');
            app.s3 = sprintf('angle: 0 ');
            app.s4 = sprintf('distL: %g  cm', app.x_position);
            app.s5 = sprintf('distR: %g cm', app.y_position);
            app.s6 = sprintf('time: 0 s');
          
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            
            % Create timer object
            app.Loop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt, ...                % Period of the function
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LoopFcn);           % Specify callback function
            
            % Start the loop
            start(app.Loop);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'a'
                    app.AButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.direction < 200)
                            app.direction = app.direction + 2;
                        end 
                    end
                case 'w'
                    app.WButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.input_force < 165)
                            app.input_force = app.input_force + 1;
                        end
                    end
                case 's'
                    app.SButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.input_force > 135)
                            app.input_force = app.input_force - 1;
                        end
                    end                  
                case 'd'
                    app.DButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        if (app.direction > 100)
                            app.direction = app.direction - 2;
                        end    
                    end   
                case 'e'                        % E brake
                    app.EButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        app.input_force = 150;
                    end 
                case 'q'                        % Pulls the car straight
                    app.QButton.Value = 1;
                    if (app.y_position <= 600 ) && (app.y_position >= 0) && (app.x_position <= 600 ) && (app.x_position >= 0)
                        app.direction = 150;
                    end 
                        
%--------------------------------------------------------------------------------%
% Space function is very messy now and not how it should be, needs to be revised %
%--------------------------------------------------------------------------------%

                case 'space'
                    app.UIAxes.cla;
                    app.input_force = 150;     % reset to initial values
                    app.direction = 150; % reset to initial values
                    app.facing = pi/2;    % reset to initial values
                    app.x_position = 200; % reset to start
                    app.y_position = 101; % reset to start
                    
                    plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
                    app.crash = 0;
                    
                    app.now = tic;
                    app.s1 = sprintf('force: 0 N');
                    app.s2 = sprintf('velocity: 0 m/s');
                    app.s3 = sprintf('angle: 0 ');
                    app.s4 = sprintf('distL: %g  cm', app.x_position);
                    app.s5 = sprintf('distR: %g cm', app.y_position);
                    app.s6 = sprintf('time: 0 s');
                    app.s7 = sprintf('crash: %g', app.crash);         %carchrash(kitt);
                    app.s8 = sprintf('zaps: ');          %kitt.numzaps;
                  
                    app.h(1) = text(app.UIAxes,5,490,app.s1);	
                    app.h(2) = text(app.UIAxes,5,470,app.s2);
                    app.h(3) = text(app.UIAxes,5,450,app.s3);
                    app.h(4) = text(app.UIAxes,5,430,app.s4);
                    app.h(5) = text(app.UIAxes,5,410,app.s5);
                    app.h(6) = text(app.UIAxes,5,390,app.s6);
                    app.h(7) = text(app.UIAxes,5,370,app.s7);
                    app.h(8) = text(app.UIAxes,5,350,app.s8);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.waypoint = app.waypoint + 1;
            plot(app.UIAxes, app.WaypointXcoordinateEditField.Value, app.WaypointYcoordinateEditField.Value, '.' ,'MarkerSize', 15, 'color', 'g')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'state');
            app.DButton.Text = 'D';
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.WaypointXcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointXcoordinateEditFieldLabel.Text = 'Waypoint X-coordinate';
            app.WaypointXcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaypointYcoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.WaypointYcoordinateEditFieldLabel.Text = 'Waypoint Y-coordinate';
            app.WaypointYcoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.QButton = uibutton(app.UIFigure, 'state');
            app.QButton.Text = 'Q';
            app.EButton = uibutton(app.UIFigure, 'state');
            app.EButton.Text = 'E';
            app.facingEditFieldLabel = uilabel(app.UIFigure);
            app.facingEditFieldLabel.Text = 'facing';
            app.facingEditField = uieditfield(app.UIFigure, 'numeric');
            app.difangleEditFieldLabel = uilabel(app.UIFigure);
            app.difangleEditFieldLabel.Text = 'dif angle';
            app.difangleEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_V5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end