classdef GUI_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AButton                matlab.ui.control.StateButton
        SButton                matlab.ui.control.StateButton
        DButton                matlab.ui.control.StateButton
        WButton                matlab.ui.control.StateButton
        UIAxes                 matlab.ui.control.UIAxes
        CrashesEditFieldLabel  matlab.ui.control.Label
        CrashesEditField       matlab.ui.control.NumericEditField
        timerEditFieldLabel    matlab.ui.control.Label
        timerEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        x_position = 200 % The starting position of the dot on x axis
        y_position = 101 % The starting position of the dot on y axis
                    
        x_box = [100, 700, 700, 100, 100]; %The outside of the arena
        y_box = [100, 100, 700, 700, 100]; %The outside of the arena
        
        crash = 0; %initialize crash as 0
        
        s1;
        s2; 
        s3
        s4;
        s5;
        s6;
        s7;
        s8;
        h;
        t;
        now;
        timer;
    end
    
    methods (Access = private)
        
        function crash = collision(app)
            if (app.x_position >= 700 ) || (app.x_position <= 100) || (app.y_position <= 100) || (app.y_position >= 700)
                app.crash = app.crash + 1;
                app.CrashesEditField.Value = app.crash;
            else
                crash = crash;
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Gives the right axis' on startup
            app.UIAxes.YLim = [0 800];
            app.UIAxes.XLim = [0 800];
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
                        
            app.now = tic;
            app.s1 = sprintf('force: 0 N');
            app.s2 = sprintf('velocity: 0 m/s');
            app.s3 = sprintf('angle: 0 ');
            app.s4 = sprintf('distL: %g  cm', app.x_position);
            app.s5 = sprintf('distR: %g cm', app.y_position);
            app.s6 = sprintf('time: 0');
          
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            app.t = toc(app.now);
            app.s1 = sprintf('force: N');       %kitt.force
            app.s2 = sprintf('velocity: m/s');  %kitt.velocity
            app.s3 = sprintf('angle: ');        %kitt.angle
            app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            app.s6 = sprintf('time: %0.2f',app.t); 
            app.s7 = sprintf('crash: ');         %carchrash(kitt);
            app.s8 = sprintf('zaps: ');          %kitt.numzaps;
            
            delete(app.h);  %delete text before writing it again
            
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            app.h(7) = text(app.UIAxes,5,370,app.s7);
            app.h(8) = text(app.UIAxes,5,350,app.s8);
            
            hold(app.UIAxes,'on') %keeps old dot locations and arena
            collision(app)
               
            switch key
                case 'a'
                    if (app.AButton.Value == 1)
                        app.AButton.Value = 0;          %switches state when a pressed
                    else
                        app.AButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            x_position_new = app.x_position - 10;       % gives a new position based on key input
                            app.x_position = x_position_new;
                            plot(app.UIAxes, app.x_position, app.y_position,'bo');
                        end                        
                    end
                case 'w'
                    if (app.WButton.Value == 1)
                        app.WButton.Value = 0;
                    else
                        app.WButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            y_position_new = app.y_position + 10;
                            app.y_position = y_position_new;
                            plot(app.UIAxes, app.x_position, app.y_position,'bo');
                        end    
                    end
                case 's'
                    if (app.SButton.Value == 1)
                        app.SButton.Value = 0;
                    else
                        app.SButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            y_position_new = app.y_position - 10;
                            app.y_position = y_position_new;
                            plot(app.UIAxes, app.x_position, app.y_position,'bo');
                        end
                    end
                case 'd'
                    if (app.DButton.Value == 1)
                        app.DButton.Value = 0;
                    else
                        app.DButton.Value = 1;
                        if (app.y_position <= 700 ) && (app.y_position >= 100) && (app.x_position <= 700 ) && (app.x_position >= 100)
                            x_position_new = app.x_position + 10;
                            app.x_position = x_position_new;
                            plot(app.UIAxes, app.x_position, app.y_position,'bo');
                        end
                    end
                case 'space'
                    app.UIAxes.cla;
                    app.x_position = 200 % reset to start
                    app.y_position = 101 % reset to start
                    plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
                    app.crash = 0;
                    app.CrashesEditField.Value = app.crash;
%                case 't'
%                      timer = cast(app.t, 'uint64');
%                      app.timerEditField.Value = toc(timer); 

                       % experimented a bit with time, since we need velocity later on, doesnt work though
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'state');
            app.DButton.Text = 'D';
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Text = 'W';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CrashesEditFieldLabel = uilabel(app.UIFigure);
            app.CrashesEditFieldLabel.Text = 'Crashes';
            app.CrashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.timerEditFieldLabel = uilabel(app.UIFigure);
            app.timerEditFieldLabel.Text = 'timer';
            app.timerEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end