classdef GUI_test_displayTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Switch    matlab.ui.control.Switch
        Lamp      matlab.ui.control.Lamp
        WButton   matlab.ui.control.StateButton
        DButton   matlab.ui.control.StateButton
        SButton   matlab.ui.control.StateButton
        AButton   matlab.ui.control.StateButton
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x_position = 200 % The starting position of the dot on x axis
        y_position = 100 % The starting position of the dot on y axis
                    
        x_box = [100, 700, 700, 100, 100]; %The outside of the arena
        y_box = [100, 100, 700, 700, 100]; %The outside of the arena
        
        s1;
        s2; 
        s3
        s4;
        s5;
        s6;
        s7;
        s8;
        h;
        t;
        now;
        timer;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % Gives the right axis' on startup
            app.UIAxes.YLim = [0 800];
            app.UIAxes.XLim = [0 800];
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-')
            
            app.now = tic;
            app.s1 = sprintf('force: 0 N');
            app.s2 = sprintf('velocity: 0 m/s');
            app.s3 = sprintf('angle: 0 ');
            app.s4 = sprintf('distL: %g  cm', app.x_position);
            app.s5 = sprintf('distR: %g cm', app.y_position);
            app.s6 = sprintf('time: 0');
          
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);

            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
           app.t = toc(app.now);
            app.s1 = sprintf('force: N');       %kitt.force
            app.s2 = sprintf('velocity: m/s');  %kitt.velocity
            app.s3 = sprintf('angle: ');        %kitt.angle
            app.s4 = sprintf('distL: %g  cm', app.x_position);  %kitt.distL
            app.s5 = sprintf('distR: %g cm', app.y_position);   %kitt.distR
            app.s6 = sprintf('time: %0.2f',app.t); 
            app.s7 = sprintf('crash: ');         %carchrash(kitt);
            app.s8 = sprintf('zaps: ');          %kitt.numzaps;
            
            delete(app.h);  %delete text before writing it again
            
            app.h(1) = text(app.UIAxes,5,490,app.s1);	
            app.h(2) = text(app.UIAxes,5,470,app.s2);
            app.h(3) = text(app.UIAxes,5,450,app.s3);
            app.h(4) = text(app.UIAxes,5,430,app.s4);
            app.h(5) = text(app.UIAxes,5,410,app.s5);
            app.h(6) = text(app.UIAxes,5,390,app.s6);
            app.h(7) = text(app.UIAxes,5,370,app.s7);
            app.h(8) = text(app.UIAxes,5,350,app.s8);
            
            hold(app.UIAxes,'on') %keeps old dot locations and arena


            switch key
                case 'a'
                    app.Lamp.Color = [1 0 0];           %lamp color used early on for debugging, can be removed
                    if (app.AButton.Value == 1)
                        app.AButton.Value = 0;          %switches state when a pressed
                    else
                        app.AButton.Value = 1;
                        x_position_new = app.x_position - 10;       % gives a new position based on key input
                        app.x_position = x_position_new;
                        plot(app.UIAxes, app.x_position, app.y_position,'bo');
                    end
                case 'w'
                    app.Lamp.Color = [0 0 1];           % w s and d have same comments as for a 
                    if (app.WButton.Value == 1)
                        app.WButton.Value = 0;
                    else
                        app.WButton.Value = 1;
                        y_position_new = app.y_position + 10;
                        app.y_position = y_position_new;
                        plot(app.UIAxes, app.x_position, app.y_position,'bo');
                    end
                case 's'
                    app.Lamp.Color = [0 1 0];
                    if (app.SButton.Value == 1)
                        app.SButton.Value = 0;
                    else
                        app.SButton.Value = 1;
                        y_position_new = app.y_position - 10;
                        app.y_position = y_position_new;
                        plot(app.UIAxes, app.x_position, app.y_position,'bo');
                    end
                case 'd'
                    app.Lamp.Color = [1 0 1];
                    if (app.DButton.Value == 1)
                        app.DButton.Value = 0;
                    else
                        app.DButton.Value = 1;
                        x_position_new = app.x_position + 10;
                        app.x_position = x_position_new;
                        plot(app.UIAxes, app.x_position, app.y_position,'bo');
                    end
%                 case 't'
%                     c = clock;
%                     seconds = c(6);
%                     app.EditField.Value = seconds; 

                        % experimented a bit with time, since we need velocity later on, doesnt work though
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.UIAxes.cla;
            app.x_position = 200 % reset to start
            app.y_position = 100 % reset to start
            plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_box, app.y_box, 'r-');
            
            
            % Only plots the initial position and arena as of now, doesnt reset anything since I struggled
            % with the app.switch.value to create some kind of if statement or something like that
            
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.AButton = uibutton(app.UIFigure, 'state');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.UIFigure, 'state');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.UIFigure, 'state');
            app.DButton.Text = 'D';
            app.WButton = uibutton(app.UIFigure, 'state');
            app.WButton.Text = 'W';
            app.Lamp = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_test_displayTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end