classdef TDOA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        xpositionEditFieldLabel      matlab.ui.control.Label
        xpositionEditField           matlab.ui.control.NumericEditField
        ypositionEditFieldLabel      matlab.ui.control.Label
        ypositionEditField           matlab.ui.control.NumericEditField
        estimatedxpositionEditFieldLabel  matlab.ui.control.Label
        estimatedxpositionEditField  matlab.ui.control.NumericEditField
        estimatedypositionEditFieldLabel  matlab.ui.control.Label
        estimatedypositionEditField  matlab.ui.control.NumericEditField
        Button                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        ref = load('reference.mat', 'reference');
        
        eps = 0.15;
        peak_eps = 0.6;
        Fs = 40000;
        
        h_1; h_2; h_3; h_4; h_5;
        
        y;
        y_out;
        
        peak_1;
        peak_2;
        peak_3;
        peak_4;
        peak_5;
        
        pos;
        kitt;
        
        est_pos_1 = [100 1];
        est_pos_2 = [0 0];
    end
    
    methods (Access = private)
        
        function TDOA_estimation(app)
            
            %Recording and estimating the 5-channel data
            
            datagen(app);
            channelEstimation(app);
            firstPeak(app);

            % Implementation of localization algorithm from Appendix B.1
            
            n_delay_12 = app.peak_1 - app.peak_2;
            t_delay_12 = (1/app.Fs)*(n_delay_12);
            r_12 = t_delay_12*34326;
            if r_12 == 0
                r_12 = 0.0001;
            end
            
            n_delay_13 = app.peak_1 - app.peak_3;
            t_delay_13 = (1/app.Fs)*(n_delay_13);
            r_13 = t_delay_13*34326;
            if r_13 == 0
                r_13 = 0.0001;
            end
            
            n_delay_14 = app.peak_1 - app.peak_4;
            t_delay_14 = (1/app.Fs)*(n_delay_14);
            r_14 = t_delay_14*34326;
            if r_14 == 0
                r_14 = 0.0001;
            end
            
            n_delay_15 = app.peak_1 - app.peak_5;
            t_delay_15 = (1/app.Fs)*(n_delay_15);
            r_15 = t_delay_15*34326;
            if r_15 == 0
                r_15 = 0.0001;
            end
            
            n_delay_23 = app.peak_2 - app.peak_3;
            t_delay_23 = (1/app.Fs)*(n_delay_23);
            r_23 = t_delay_23*34326;
            if r_23 == 0
                r_23 = 0.0001;
            end
            
            n_delay_24 = app.peak_2 - app.peak_4;
            t_delay_24 = (1/app.Fs)*(n_delay_24);
            r_24 = t_delay_24*34326;
            if r_24 == 0
                r_24 = 0.0001;
            end
            
            n_delay_25 = app.peak_2 - app.peak_5;
            t_delay_25 = (1/app.Fs)*(n_delay_25);
            r_25 = t_delay_25*34326;
            if r_25 == 0
                r_25 = 0.0001;
            end
            
            n_delay_34 = app.peak_3 - app.peak_4;
            t_delay_34 = (1/app.Fs)*(n_delay_34);
            r_34 = t_delay_34*34326;
            if r_34 == 0
                r_34 = 0.0001;
            end
            
            n_delay_35 = app.peak_3 - app.peak_5;
            t_delay_35 = (1/app.Fs)*(n_delay_35);
            r_35 = t_delay_35*34326;
            if r_35 == 0
                r_35 = 0.0001;
            end
            
            n_delay_45 = app.peak_4 - app.peak_5;
            t_delay_45 = (1/app.Fs)*(n_delay_45);
            r_45 = t_delay_45*34326;
            if r_45 == 0
                r_45 = 0.0001;
            end
            
            %positions of the mics
            
            x1 = [0; 0];
            x2 = [0; 600];
            x3 = [600; 0];
            x4 = [600; 600];
            x5 = [300; 0];
            
            A = [2*((x2-x1).') -2*r_12 0 0 0;
                2*((x3-x1).') 0 -2*r_13 0 0;
                2*((x4-x1).') 0 0 -2*r_14 0;
                2*((x5-x1).') 0 0 0 -2*r_15;
                2*((x3-x2).') 0 -2*r_23 0 0;
                2*((x4-x2).') 0 0 -2*r_24 0;
                2*((x5-x2).') 0 0 0 -2*r_25;
                2*((x4-x3).') 0 0 -2*r_34 0;
                2*((x5-x3).') 0 0 0 -2*r_35;
                2*((x5-x4).') 0 0 0 -2*r_45];
            
            b = [r_12^2-(norm(x1))^2+(norm(x2))^2;
                r_13^2-(norm(x1))^2+(norm(x3))^2;
                r_14^2-(norm(x1))^2+(norm(x4))^2;
                r_15^2-(norm(x1))^2+(norm(x5))^2;
                r_23^2-(norm(x2))^2+(norm(x3))^2;
                r_24^2-(norm(x2))^2+(norm(x4))^2;
                r_25^2-(norm(x2))^2+(norm(x5))^2;
                r_34^2-(norm(x3))^2+(norm(x4))^2;
                r_35^2-(norm(x3))^2+(norm(x5))^2;
                r_45^2-(norm(x4))^2+(norm(x5))^2];
            
            A_t = inv((A.')*A);
            app.y_out = (A_t)*(A.')*b;
            
            discard_estimation(app);
            
            app.estimatedxpositionEditField.Value = app.est_pos_1(1);
            app.estimatedypositionEditField.Value = app.est_pos_1(2);            
        end
    
        function discard_estimation(app)
            
            app.est_pos_2 = app.est_pos_1;
            app.est_pos_1 = app.y_out; 
            
            estimation_distance = [app.est_pos_1(1)-app.est_pos_2(1) app.est_pos_1(2)-app.est_pos_2(2)];
            estimation_variance = norm(estimation_distance);
            
            if (app.est_pos_1(1) >= 600) || (app.est_pos_1(1) <= 0) || (app.est_pos_1(2) <= 0) || (app.est_pos_1(2) >= 600) 
                app.est_pos_1 = app.est_pos_2;
            elseif (estimation_variance >= 100)
                app.est_pos_1 = app.est_pos_2;
            end

        end             
    
        function datagen(app)
            app.y = EPOCommunications('receive','N16000'); %receive audio signal
        end
   
        function channelEstimation(app)
            
            Ny = length(app.y(:,1));     %length of recorded signal 
            Nx = length(app.ref);     %length of reference signal

            Y_1 = fft(app.y(:,1));         %fft to get y in frequency domain
            Y_2 = fft(app.y(:,2));
            Y_3 = fft(app.y(:,3));
            Y_4 = fft(app.y(:,4));
            Y_5 = fft(app.y(:,5));
            
            tempref = app.ref.reference;
            
            X = fft(tempref); %fft to get x in frequency domain
            ii = find(abs(X) > app.eps*max(abs(X))); %assuring the noise is filtered out
            
            for n = 1:length(X)
                for i = 1:length(ii)
                    if n == ii(i)
                        G(n)=1;
                        break
                    else
                        G(n)=0;
                    end
                end
            end
            G=G(:);
            
            % channel mic 1
            H_1 = Y_1./X;
            H_1 = G.*H_1;
            TF = isnan(H_1);
            H_1(TF) = 0;
            app.h_1 = ifftshift(ifft(H_1));
            
            % Channel mic 2
            H_2 = Y_2./X;
            H_2 = G.*H_2;
            TF = isnan(H_2);
            H_2(TF) = 0;
            app.h_2 = ifftshift(ifft(H_2));
            
            %Channel mic 3
            H_3 = Y_3./X;
            H_3 = G.*H_3;
            TF = isnan(H_3);
            H_3(TF) = 0;
            app.h_3 = ifftshift(ifft(H_3));
            
            %Channel mic 4
            H_4 = Y_4./X;
            H_4 = G.*H_4;
            TF = isnan(H_4);
            H_4(TF) = 0;
            app.h_4 = ifftshift(ifft(H_4));
            
            %channel mic 5
            H_5 = Y_5./X;
            H_5 = G.*H_5;
            TF = isnan(H_5);
            H_5(TF) = 0;
            app.h_5 = ifftshift(ifft(H_5));
        end
    
        function firstPeak(app)
            
            %findpeaks 5 peaks
            [pks_1,locs_1] = findpeaks(abs(app.h_1));
            [pks_2,locs_2] = findpeaks(abs(app.h_2));
            [pks_3,locs_3] = findpeaks(abs(app.h_3));
            [pks_4,locs_4] = findpeaks(abs(app.h_4));
            [pks_5,locs_5] = findpeaks(abs(app.h_5));
            
            % allpeaks
            allpeaks_1 = find(pks_1>app.peak_eps*max(app.h_1));
            allpeaks_2 = find(pks_2>app.peak_eps*max(app.h_2));
            allpeaks_3 = find(pks_3>app.peak_eps*max(app.h_3));
            allpeaks_4 = find(pks_4>app.peak_eps*max(app.h_4));
            allpeaks_5 = find(pks_5>app.peak_eps*max(app.h_5));
            
            % locate all peaks
            app.peak_1 = locs_1(allpeaks_1(1));
            app.peak_2 = locs_2(allpeaks_2(1));
            app.peak_3 = locs_3(allpeaks_3(1));
            app.peak_4 = locs_4(allpeaks_4(1));
            app.peak_5 = locs_5(allpeaks_5(1));
        end
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %clear all;
            app.pos = [app.xpositionEditField.Value; app.ypositionEditField.Value];
            
            EPOCommunications('init',['X' mat2str(app.pos)]); % initial position of car
            EPOCommunications('init','D[0;1]'); % initial direction of car
            
            P = [
                0 0 600 600 300;
                0 600 0 600 0;
                20 20 20 20 70]; % positions of mics
            
            EPOCommunications('init',['P' mat2str(P)]); % initialize positions of mics
            EPOCommunications('init','J40000'); % set sample rate of mics (Hz)
            EPOCommunications('init','N2000'); % set number of samples to return
            
            B1 = [
                -50 650 650 -50;
                -50 -50 650 650];	% outer arena boundary (outside bounding box)
            NAN = [NaN; NaN];	% spacer between objects
            B2 = [
                -21 621 621 -21;
                -21 -21 621 621];	% inner arena boundary (hole since it overlaps B1)
            bb = mat2str([B1 NAN B2]);
            EPOCommunications('init',['O' bb]);	% initialize arena boundary
            
            EPOCommunications('open');
            
            EPOCommunications('transmit', 'A1'); % switch on audio beacon
            
            EPOCommunications('transmit', 'B5000'); % set the bit frequency
            EPOCommunications('transmit', 'F20000');% set the carrier frequency
            EPOCommunications('transmit', 'R2000'); % set the repetition count
            EPOCommunications('transmit', 'C0xE04869A5'); % set the audio code
            
            TDOA_estimation(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.xpositionEditFieldLabel = uilabel(app.UIFigure);
            app.xpositionEditFieldLabel.Text = 'x-position';
            app.xpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ypositionEditFieldLabel = uilabel(app.UIFigure);
            app.ypositionEditFieldLabel.Text = 'y-position';
            app.ypositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.estimatedxpositionEditFieldLabel = uilabel(app.UIFigure);
            app.estimatedxpositionEditFieldLabel.Text = 'estimated x-position';
            app.estimatedxpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.estimatedypositionEditFieldLabel = uilabel(app.UIFigure);
            app.estimatedypositionEditFieldLabel.Text = 'estimated y-position';
            app.estimatedypositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = TDOA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end