classdef Midterm_Challenge_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        StartpositionEditFieldLabel   matlab.ui.control.Label
        StartpositionEditField        matlab.ui.control.NumericEditField
        StoppositionEditFieldLabel    matlab.ui.control.Label
        StoppositionEditField         matlab.ui.control.NumericEditField
        DistancetowallEditFieldLabel  matlab.ui.control.Label
        DistancetowallEditField       matlab.ui.control.NumericEditField
        TimerEditFieldLabel           matlab.ui.control.Label
        TimerEditField                matlab.ui.control.NumericEditField
        RecognizedcommandEditFieldLabel  matlab.ui.control.Label
        RecognizedcommandEditField    matlab.ui.control.EditField
        PlotButton                    matlab.ui.control.Button
        FirstInputStartandStoppositionbeforePlotLabel  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        StartListeningButton          matlab.ui.control.Button
        Lamp_2                        matlab.ui.control.Lamp
        SpeedmsGaugeLabel             matlab.ui.control.Label
        SpeedmsGauge                  matlab.ui.control.Gauge
        brakesButton                  matlab.ui.control.StateButton
        DelayButton                   matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        x_start = 0;            % starting positions
        y_start = -200;
        x_position = 50;        % straight line on Y-axis so x is fixed
        y_position;             % position of the car
        MainLoop;               % The 2 loops
        DistanceLoop;
        crash = 0;                  % initialize crash as 0
        now;                        % timer
        plotted = 0;                % initialize plot as 0
        brakes = 0;                 % initialize brakes as 0
        delay_on = 0;               % initialize delay as off
        finish = 0;                 % initialize finish as 0
        tt = 0.02;                  % The period of the loops
        flag = 0;                   % initialize flag as 0
        random;                     % random delay
        received_distance = 999;     
        int_value = 999;            % initialize delay distances as any arbitrary high number to prevent crashes
        prev_val1 = 999;
        prev_val2 = 999;
        command_state = 0;          % initialize state as 0
        go_command = 0;             % initialize go command as 0
        y_test_pred = 0;            % initialize command prediction as 0
        
        car_outline = 0;
        
        % Car parameters
        input_force = 150;
        m = 5.6;
        b = 5;
        c = 0.1;
        
        force = 0;          % Force pressed on trigger
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        deadspeed = 0;      % Speed of car with deadzone
        facing = pi/2;      % Direction the car is facing in rad 0 = x
        
        x_wall = [-140, 240];   % The wall at the origin
        y_wall = [0, 0];       % The wall at the origin
        
        x_finish = [-70, 170]; % The finish line placed at stop position
        y_finish;              % The finish line placed at stop position
        
        % Command prediction variables
        varr=load('bakgijstop.mat');
        threshold= 0.1;
        Fs = 8000;
        L= 160;
        ov= 80;
        prediction;
        y;
        y_norm;
        y_nosilence;
        x_test;
        f0;
        frmtns;
    end
    
    methods (Access = private)
                    
        function control(app)                       
            if app.delay_on == 1            % account for delay when delay button pushed
                time_delay = 3*app.tt;
            else
                time_delay = app.tt;
            end
            
            total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
            total_force = app.force - app.b*app.speed - app.c*app.speed^2;
            projected_distance = abs(app.received_distance - app.StoppositionEditField.Value) - 100*app.speed*time_delay - 100*total_force_a*time_delay^2/(2*app.m); %in CM!!!!!!
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            braking_velocity = @(t) exp(-(100/112)*t)*(2+projected_speed)-2;
            upperlim = -112*log(10/(10+5*projected_speed))/100;
            braking_distance = integral(braking_velocity,0,upperlim);
           
            if (100*braking_distance >= projected_distance || app.flag == 1) && app.brakes == 1
                if app.speed <= 0.045
                    app.input_force = 150;  % "coasting" state when nearing endpoint
                    app.flag = 2;
                else
                    app.input_force = 135;  % "hard brake" state when nearing endpoint
                    app.flag = 1;
                end
            elseif abs(app.received_distance - app.StoppositionEditField.Value) >= 5 && app.flag == 0 && app.go_command == 1
                app.input_force = 165;      % "go" state
                app.flag = 0;
            else
                app.input_force = 150;      % Idle state
                app.flag = 2;
            end
        end
        
        function collision(app)
            % Checks if the car is outside of the arena
            if (app.y_position >= 0)
                app.speed = 0;
                app.crash = 1;
                stop(app.MainLoop);     % If crashed stop the loop
                stop(app.DistanceLoop); % If crashed stop the loop
                
                % Plot the crash location
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
            end
        end
    
        function waypointdistance(app)
            % Calculate the distance to the finish
            app.DistancetowallEditField.Value = abs(0 - app.y_position);
        end
    
        function finishline(app)
            % Stops the timer when car has crossed the finish line
            if app.plotted == 1 && toc(app.now) > 1 && app.deadspeed == 0 && abs(app.received_distance - app.StoppositionEditField.Value) <= 50 % if car goes slower than 0.5 mm per second
                app.finish = 1;
                stop(app.MainLoop);             % Stops everything when finished
                stop(app.DistanceLoop);
            end
        end
        
        function velocity(app)
            % Speed is calculated in m/s now, so *100 is added on the display in the figure
            app.force = (app.input_force - 150)*2/3;
            app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m - app.c*app.speed^2*app.tt/app.m;
            if abs(app.speed) < 0.001
                app.deadspeed = 0;              % If car goes VERY slow set speed to 0 (deadzone)
            else
                app.deadspeed = app.speed;
            end
            app.SpeedmsGauge.Value = app.deadspeed;
        end
        
        function MainLoopFcn(app,~,~)
            
            % Only checks if the car has finished if the car has started
             if app.plotted == 1 && app.go_command == 1
                 finishline(app);        % Checks if the car has crossed the finish line
             end
            
            % Only display time when positions are known
            if (app.plotted == 1) && (app.finish == 0) && app.go_command == 1
                app.TimerEditField.Value = toc(app.now);   % Display time in edit field
            end
            
            collision(app);         % Checks every tt seconds if the car has crashed in the wall
            velocity(app);          % calculates every tt seconds the speed of the car
            
            % Determine new position based on force and angle
            app.facing = app.facing + app.deadspeed*app.tt*tan((app.direction - 150)*0.45/50)/0.335;
            app.x_position = app.x_position + 100*app.deadspeed*app.tt * cos(app.facing); %converted m to cm
            app.y_position = app.y_position + 100*app.deadspeed*app.tt * sin(app.facing); %converted m to cm           
            
            % Only display distance when positions are known
            if (app.plotted == 1)
                waypointdistance(app);  % Checks every tt seconds the distance to the waypoint if a waypoint is present
            end
            
            % If statement to prevent plotting outside the arena
            if (app.crash == 0) && (app.plotted == 1)
                app.car_outline = polyshape([app.x_position-21 app.x_position-21 app.x_position+21 app.x_position+21],[app.y_position app.y_position-52 app.y_position-52 app.y_position]);
                plot(app.UIAxes, app.x_wall, app.y_wall, 'r-', app.x_finish, app.y_finish, 'g-')
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.car_outline);
                hold(app.UIAxes, 'off');
            end
            
            % Only control the car when positions are known and started
            if app.plotted == 1 && app.go_command == 1
                control(app);   % Function to control the car
            end
        end
        
        function DistanceLoopFcn(app,~,~)
            
            app.random = 10 + (30-10) .* rand(1,1);     % Creates random delay between 10 and 30 ms
            
             if app.random <= 16.666
                 app.received_distance = app.prev_val1;
             elseif app.random <= 23.333
                 app.received_distance = app.prev_val2;
             else
                 app.received_distance = app.int_value;
            end
            if app.plotted == 1
                app.int_value = app.prev_val2;
                app.prev_val2 = app.prev_val1;
                app.prev_val1 = app.DistancetowallEditField.Value;
            end
            
            if app.delay_on == 0
                app.received_distance = app.DistancetowallEditField.Value;
            end
        end
        
        
        function PredictVoice(app)
            recObj = audiorecorder(app.Fs,16,1); % create audio object, 16 bits resolution
            app.Lamp_2.Color = 'green';
            recordblocking(recObj, 0.8); % do a 2 second recording (blocking)
            app.Lamp_2.Color = 'red';
            app.y = getaudiodata(recObj);
            
            % extract feat
            ExtractFeat(app);
            
            % standard normalize
            app.x_test = (app.x_test-app.varr.mu_train)./app.varr.sigma_train;
            
            % predict
            app.y_test_pred = str2double(app.varr.model.predict(app.x_test));
            
            if(app.y_test_pred == 1)
                app.RecognizedcommandEditField.Value = 'Bak';
            elseif(app.y_test_pred == 2)
                app.RecognizedcommandEditField.Value = 'Gij';
            elseif(app.y_test_pred == 3)
                app.RecognizedcommandEditField.Value = 'Stop';
            end
            
            if app.y_test_pred == 1 && app.command_state == 0 % Bak state
                PlotButtonPushed(app);      % Initializes the plot with the inputs (former button is made invisible however functions are similar)
                app.command_state = 1;      % Gives access to the "go" state
                
            elseif app.y_test_pred == 2 && app.command_state == 1 % Gij state
                app.go_command = 1;         % Gives the car the command to go
                app.command_state = 2;      % Gives access to the "stop" state
                app.now = tic;              % starts timer when car starts driving
                
            elseif app.y_test_pred == 3 && app.command_state == 2 % Stop state
                brakesButtonValueChanged(app);    % Executes the brakes (former button is made invisible however functions are similar)
                app.command_state = 3;      % Prevents the car to go back a state 
            end
        end
        
        function ExtractFeat(app)
            % normalize y
            % remove mean
            app.y_norm= app.y - sum(app.y)/length(app.y);
            % devide by the max abs value
            app.y_norm= app.y_norm / max(abs(app.y_norm));
            
            % remove silence
            RemoveSilence(app);
            
            % find pitch
            FindPitch(app);
            
            % find formants
            FormantsEpo4(app);
            
            % find Zero Crossing Rate
            x10 = sum(abs(diff(app.y_nosilence>0)))/length(app.y_nosilence);
            
            % features
            % mean, median, and variance of the pitch:
            x1=mean(app.f0,'omitnan');
            x2=median(app.f0,'omitnan');
            x3=var(app.f0,'omitnan');
            
            % mean, median, and variance of each formants
            x4=mean(app.frmtns,1,"omitnan");
            x5=median(app.frmtns,1,"omitnan");
            x6=var(app.frmtns,1,"omitnan");
            
            % mean, median, and variance of F1/F2
            f1f2=app.frmtns(:,1)./app.frmtns(:,2); % F1/F2
            x7=mean(f1f2,'omitnan');
            x8=median(f1f2,'omitnan');
            x9=var(f1f2,'omitnan');
            
            % putting all the features together: size 1*16
            app.x_test=[x1,x2,x3,x4,x5,x6,x7,x8,x9,x10];
        end
        
        function RemoveSilence(app)
            % find() samples that have amplitudes larger than the threshold
            nonsilent_samples = find(app.y>app.threshold, 1, 'first'):find(app.y>app.threshold, 1, 'last');
            t_start=nonsilent_samples(1);
            t_end=nonsilent_samples(end);
            
            % only keep the signal between the first and last sample
            app.y_nosilence= app.y( t_start: t_end);
        end
        
        function FindPitch(app)
            app.f0 = pitch(app.y, app.Fs, 'WindowLength', app.L, 'OverlapLength', app.ov, 'Method', 'CEP');
        end
        
        function FormantsEpo4(app)
            %% Formant estimation in a moving window approach
            
            N = length(app.y);         % length of the audio signal
            win = hann(app.L);         % window
            preemph = [1 0.63];        % Coefficients of the pre-emphasis filter (Highpass)
            count = 0;
            lng = floor((N-app.L)/(app.ov))+1; % Number of windows within the signal
            app.frmtns = NaN(lng,3,'double');

            
            for iter = 1:app.ov:(N - app.L) % Formant estimation for each window of length L
                count = count+1;
                tmp = app.y(iter:iter+app.L-1,:);
                y1 = tmp.*win;
                y1 = filter(1,preemph,y1);
                A = lpc(y1,8);          % Linear predictor coefficients
                rts = roots(A);
                rts = rts(imag(rts)>=0);
                angz = atan2(imag(rts),real(rts));
                [frqs,indices] = sort(angz.*(app.Fs/(2*pi)));
                bw = -1/2*(app.Fs/(2*pi))*log(abs(rts(indices)));
                nn=1; frs = NaN(1,3,'double');
                for kk = 1:length(frqs)
                    if (frqs(kk) > 90 && bw(kk) <400)
                        frs(nn) = frqs(kk);
                        nn = nn+1;
                    end
                end
                app.frmtns(count,:) = frs(1:3);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize lamp colors
            app.Lamp_2.Color = 'red';
            app.Lamp.Color = 'red';
            
            % Initialize arbitrary arena
            app.UIAxes.YLim = [-300 100];
            app.UIAxes.XLim = [-150 250];
            
            % Initialize arbitrary car location
            app.car_outline = polyshape([app.x_start-21 app.x_start-21 app.x_start+21 app.x_start+21],[app.y_start app.y_start-52 app.y_start-52 app.y_start]);
            plot(app.UIAxes, app.x_wall, app.y_wall, 'r-');
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.car_outline);
            hold(app.UIAxes, 'off');
            
            % Create timer object
            app.MainLoop = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
                'Period', app.tt, ...                   % Period is tt second
                'BusyMode', 'queue',...                 % Queue timer callbacks when busy
                'TimerFcn', @app.MainLoopFcn);          % Specify callback function
                        
            % Create timer object
            app.DistanceLoop = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
                'Period', app.tt, ...                   % Period is tt second
                'BusyMode', 'queue',...                 % Queue timer callbacks when busy
                'TimerFcn', @app.DistanceLoopFcn);      % Specify callback function
            
            % Start the loop
            start(app.MainLoop);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Prevents using the function multiple times
            if (app.plotted == 0)
               
                % Creating the plot limits using the start input
                app.y_position = -1*app.StartpositionEditField.Value;
                y_lim = app.y_position - 100;
                app.UIAxes.YLim = [y_lim 100];
                
                % X limit changes based on ylim to keep the car having a rectangular form
                x_lim = 100 - app.y_position;
                x_neg = -1*x_lim/2 + 50;
                x_pos = x_lim/2 + 50;
                app.UIAxes.XLim = [x_neg x_pos];
                
                % Determine end line
                app.y_finish = [-1*app.StoppositionEditField.Value, -1*app.StoppositionEditField.Value];
                
                % Plot the starting position, wall and end point
                plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_wall, app.y_wall, 'r-', app.x_finish, app.y_finish, 'g-');
                
                % Set speed, force and flag
                app.flag = 0;
                app.speed = 0;
                app.input_force = 150;
                
                % Start Timer and indicate plot has been made
                app.plotted = 1;
                
                % Distances are now known so distanceloop can be started
                start(app.DistanceLoop);
            end      
        end

        % Button pushed function: StartListeningButton
        function StartListeningButtonPushed(app, event)
            PredictVoice(app);      % Listens to command
        end

        % Value changed function: brakesButton
        function brakesButtonValueChanged(app, event)
            % Enables the brakes
            if app.plotted == 1 && app.brakes == 0
                app.brakes = 1;
            end
        end

        % Value changed function: DelayButton
        function DelayButtonValueChanged(app, event)
            % Determines if delay is activated in distanceloop 
            if app.DelayButton.Value == 0
                app.Lamp.Color = 'red';
                app.delay_on = 0;
            elseif app.DelayButton.Value == 1
                app.Lamp.Color = 'green';
                app.delay_on = 1;
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Midterm Challenge Group A05')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartpositionEditFieldLabel = uilabel(app.UIFigure);
            app.StartpositionEditFieldLabel.Text = 'Start position';
            app.StartpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoppositionEditFieldLabel = uilabel(app.UIFigure);
            app.StoppositionEditFieldLabel.Text = 'Stop position';
            app.StoppositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancetowallEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetowallEditFieldLabel.Text = 'Distance to wall ';
            app.DistancetowallEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimerEditFieldLabel = uilabel(app.UIFigure);
            app.TimerEditFieldLabel.Text = 'Timer';
            app.TimerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecognizedcommandEditFieldLabel = uilabel(app.UIFigure);
            app.RecognizedcommandEditFieldLabel.Text = 'Recognized command';
            app.RecognizedcommandEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Visible = 'off';
            app.PlotButton.Text = 'Plot';
            app.FirstInputStartandStoppositionbeforePlotLabel = uilabel(app.UIFigure);
            app.FirstInputStartandStoppositionbeforePlotLabel.Text = 'First Input Start and Stop position before Plot';
            app.Lamp = uilamp(app.UIFigure);
            app.StartListeningButton = uibutton(app.UIFigure, 'push');
            app.StartListeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartListeningButtonPushed, true);
            app.StartListeningButton.Text = 'Start Listening';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.SpeedmsGaugeLabel = uilabel(app.UIFigure);
            app.SpeedmsGaugeLabel.Text = 'Speed (m/s)';
            app.SpeedmsGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedmsGauge.Limits = [0 2];
            app.brakesButton = uibutton(app.UIFigure, 'state');
            app.brakesButton.ValueChangedFcn = createCallbackFcn(app, @brakesButtonValueChanged, true);
            app.brakesButton.Visible = 'off';
            app.brakesButton.Text = 'brakes';
            app.DelayButton = uibutton(app.UIFigure, 'state');
            app.DelayButton.ValueChangedFcn = createCallbackFcn(app, @DelayButtonValueChanged, true);
            app.DelayButton.Text = 'Delay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Midterm_Challenge_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end