classdef Midterm_Challengev3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        StartpositionEditFieldLabel   matlab.ui.control.Label
        StartpositionEditField        matlab.ui.control.NumericEditField
        StoppositionEditFieldLabel    matlab.ui.control.Label
        StoppositionEditField         matlab.ui.control.NumericEditField
        DistancetowallEditFieldLabel  matlab.ui.control.Label
        DistancetowallEditField       matlab.ui.control.NumericEditField
        TimerEditFieldLabel           matlab.ui.control.Label
        TimerEditField                matlab.ui.control.NumericEditField
        RecognizedcommandEditFieldLabel  matlab.ui.control.Label
        RecognizedcommandEditField    matlab.ui.control.EditField
        PlotButton                    matlab.ui.control.Button
        FirstInputStartandStoppositionbeforePlotLabel  matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        DelayButton                   matlab.ui.control.Button
        StartListeningButton          matlab.ui.control.Button
        Lamp_2                        matlab.ui.control.Lamp
        SpeedmsGaugeLabel             matlab.ui.control.Label
        SpeedmsGauge                  matlab.ui.control.Gauge
        EditField                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        x_start = 0;
        y_start = -200;
        x_position = 50;
        y_position;
        MainLoop;
        DistanceLoop;
        crash = 0;
        now;
        plotted = 0;
        finish = 0;
        tt = 0.02;
        flag = 0;
        random;
        received_distance = 999;
        int_value;
        
        input_force = 150;
        m = 5.6;
        b = 5;
        c = 0.1;
        
        force = 0;        % Force pressed on trigger
        direction = 150;    % Direction of wheels
        speed = 0;          % Speed of car
        deadspeed = 0;      % Speed of car with deadzone
        facing = pi/2;      % Direction the car is facing in rad 0 = x
        
        x_wall = [-50, 150];   % The wall at the origin
        y_wall = [0, 0];       % The wall at the origin
        
        x_finish = [-25, 125]; % The finish line placed at stop position
        y_finish;              % The finish line placed at stop position
    end
    
    methods (Access = private)
        
        function control(app)            
            time_delay = app.tt + 20/1000;
            total_force_a = abs(app.force) - app.b*app.speed - app.c*app.speed^2;
            total_force = app.force - app.b*app.speed - app.c*app.speed^2;
            projected_distance = abs(app.received_distance - app.StoppositionEditField.Value) - app.speed*time_delay - total_force_a*time_delay^2/(2*app.m);
            projected_speed = app.speed + total_force*time_delay/app.m;
            
            %braking_distance = abs(app.m*(projected_speed^2)/(2*10)); 
            braking_velocity = @(t) exp(-(100/112)*t)*(2+projected_speed)-2;
            upperlim = -112*log(10/(10+5*projected_speed))/100;
            braking_distance = integral(braking_velocity,0,upperlim);

            %if projected_speed <= 0.0005 && projected_distance <= 5
            %    app.input_force = 150;
            %    app.flag = 2;
            %end
            
            if 100*braking_distance >= projected_distance || app.flag == 1
                if app.speed <= 0.045
                    app.input_force = 150;
                    app.flag = 2;
                else
                    app.input_force = 135;
                    app.flag = 1;
                end
            elseif abs(app.received_distance - app.StoppositionEditField.Value) >= 5 && app.flag == 0
                app.input_force = 165;
                app.flag = 0;
            else
                app.input_force = 150;
                app.flag = 2;
            end
        end
        
        function collision(app)
            % Checks if the car is outside of the arena
            if (app.y_position >= 0)
                app.speed = 0;
                app.crash = 1;
                stop(app.MainLoop);     % If crashed stop the loop
                
                % Plot the crash location
                plot(app.UIAxes, app.x_position, app.y_position,'x','MarkerSize', 40, 'color', 'r', 'LineWidth', 2)
            end
        end
    
        function waypointdistance(app)
            % Calculate the distance to the finish
            app.DistancetowallEditField.Value = abs(0 - app.y_position);
        end
    
        function finishline(app)
            % Stops the timer when car has crossed the finish line
            if app.plotted == 1 && toc(app.now) > 1 && app.deadspeed == 0  % if car goes slower than 0.5 mm per second 
                app.finish = 1;
                stop(app.MainLoop);
                stop(app.DistanceLoop);
            end
        end
    
        function velocity(app)
        % Speed is calculated in m/s now, so *100 is added on the display in the figure
            app.force = (app.input_force - 150)*2/3;
            app.speed = app.speed + app.force*app.tt/app.m - app.b*app.speed*app.tt/app.m - app.c*app.speed^2*app.tt/app.m;
            if abs(app.speed) < 0.0005
                app.deadspeed = 0;
            else
                app.deadspeed = app.speed;
            end
            app.SpeedmsGauge.Value = app.deadspeed;
        end
        
        function MainLoopFcn(app,~,~)
            
            hold(app.UIAxes,'on')   % Keeps old dot locations and arena
            
            finishline(app);        % Checks if the car has crossed the finish line
            
            % Only display time when positions are known
            if (app.plotted == 1) && (app.finish == 0)
                app.TimerEditField.Value = toc(app.now);   % Display time in edit field
            end
            
            collision(app);         % Checks every tt seconds if the car has crashed in the wall
            velocity(app);
            
            % Determine new position based on force and angle
            app.facing = app.facing + app.deadspeed*app.tt*tan((app.direction - 150)*0.45/50)/0.335;
            app.x_position = app.x_position + 100*app.deadspeed*app.tt * cos(app.facing); %converted m to cm
            app.y_position = app.y_position + 100*app.deadspeed*app.tt * sin(app.facing); %converted m to cm
            
            
            % Only display distance when positions are known
            if (app.plotted == 1)
                waypointdistance(app);  % Checks every tt seconds the distance to the waypoint if a waypoint is present
            end
            
            % If statement to prevent plotting another dot outside the arena
            if (app.crash == 0) && (app.plotted == 1)
                plot(app.UIAxes, app.x_position, app.y_position,'bo');
            end
            
            control(app);
        end
    
         function DistanceLoopFcn(app,~,~)                 
            app.random = 10 + (30-10) .* rand(1,1);
            
            app.int_value = app.DistancetowallEditField.Value;
            pause(app.random/1000);
            app.received_distance = app.int_value;
         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.YLim = [-300 100];
            app.UIAxes.XLim = [-100 200];
            plot(app.UIAxes, app.x_start, app.y_start,'bo', app.x_wall, app.y_wall, 'r-');
            
            % Create timer object
            app.MainLoop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt, ...                   % Period is 0.01 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MainLoopFcn);           % Specify callback function
                        
            % Create timer object
            app.DistanceLoop = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', app.tt, ...                   % Period is 0.01 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.DistanceLoopFcn);           % Specify callback function
            
            % Start the loop
            start(app.MainLoop);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if (app.plotted == 0)
                % Clear the original location
                app.UIAxes.cla;
                
                % Creating the plot limits using the start input
                app.y_position = -1*app.StartpositionEditField.Value;
                y_lim = app.y_position - 100;
                app.UIAxes.YLim = [y_lim 100];
                
                % X limit is fixed
                app.UIAxes.XLim = [-100 200];
                
                % Determine end line
                app.y_finish = [-1*app.StoppositionEditField.Value, -1*app.StoppositionEditField.Value];
                
                % Plot the starting position, wall and end point
                plot(app.UIAxes, app.x_position, app.y_position,'bo', app.x_wall, app.y_wall, 'r-', app.x_finish, app.y_finish, 'g-');
                
                % Set speed, force and flag
                app.flag = 0;
                app.speed = 0;
                app.input_force = 150;
                
                % Start Timer and indicate plot has been made
                app.plotted = 1;
                app.now = tic;
                
                start(app.DistanceLoop);
            end     
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            %-----------------------------------------------------------------------%
            %   This function should be replaced with the voice controlled version  %
            %-----------------------------------------------------------------------%
            
            key = event.Key;
            
            switch key
                 case 'w'
                    if (app.y_position < 0)
                        if (app.force < 165)
                            % Apply new values depending on button press
                            app.input_force = 165;
                        end
                    end
                case 's'
                    % Apply new values depending on button press
                    app.input_force= 135;
                    
                case 'space'
                    app.input_force = 150;
            end
        end

        % Button pushed function: DelayButton
        function DelayButtonPushed(app, event)
            
        end

        % Value changed function: DistancetowallEditField
        function DistancetowallEditFieldValueChanged(app, event)
            value = app.DistancetowallEditField.Value;
            %pause(app.random/1000);
            app.received_distance = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Midterm Challenge Group A05')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartpositionEditFieldLabel = uilabel(app.UIFigure);
            app.StartpositionEditFieldLabel.Text = 'Start position';
            app.StartpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoppositionEditFieldLabel = uilabel(app.UIFigure);
            app.StoppositionEditFieldLabel.Text = 'Stop position';
            app.StoppositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancetowallEditFieldLabel = uilabel(app.UIFigure);
            app.DistancetowallEditFieldLabel.Text = 'Distance to wall ';
            app.DistancetowallEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancetowallEditField.ValueChangedFcn = createCallbackFcn(app, @DistancetowallEditFieldValueChanged, true);
            app.TimerEditFieldLabel = uilabel(app.UIFigure);
            app.TimerEditFieldLabel.Text = 'Timer';
            app.TimerEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecognizedcommandEditFieldLabel = uilabel(app.UIFigure);
            app.RecognizedcommandEditFieldLabel.Text = 'Recognized command';
            app.RecognizedcommandEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FirstInputStartandStoppositionbeforePlotLabel = uilabel(app.UIFigure);
            app.FirstInputStartandStoppositionbeforePlotLabel.Text = 'First Input Start and Stop position before Plot';
            app.Lamp = uilamp(app.UIFigure);
            app.DelayButton = uibutton(app.UIFigure, 'push');
            app.DelayButton.ButtonPushedFcn = createCallbackFcn(app, @DelayButtonPushed, true);
            app.DelayButton.Text = 'Delay';
            app.StartListeningButton = uibutton(app.UIFigure, 'push');
            app.StartListeningButton.Text = 'Start Listening';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.SpeedmsGaugeLabel = uilabel(app.UIFigure);
            app.SpeedmsGaugeLabel.Text = 'Speed (m/s)';
            app.SpeedmsGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedmsGauge.Limits = [0 2];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Midterm_Challengev3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end