classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        varr=load('bakgijstop.mat');
        threshold= 0.1;
        Fs = 8000;
        L= 160;
        ov= 80;
        prediction;
        y;
        y_norm;
        y_nosilence;
        x_test;
        f0;
        frmtns;
    end
    
    methods (Access = public)
        function PredictVoice(app)
            recObj = audiorecorder(app.Fs,16,1); % create audio object, 16 bits resolution
            msgbox('Start speaking...');
            recordblocking(recObj, 2); % do a 2 second recording (blocking)
            msgbox('End of Recording.');
            app.y = getaudiodata(recObj);

            % extract feat
            ExtractFeat(app);

            % standard normalize
            app.x_test = (app.x_test-app.varr.mu_train)./app.varr.sigma_train;

            % predict
            y_test_pred = str2double(app.varr.model.predict(app.x_test));
            
            % plot
            % app.UIAxes.plot(app.y)
            plot(app.UIAxes, app.y);

            if(y_test_pred == 1)
                app.EditField.Value = 'Bak';
            elseif(y_test_pred == 2)
                app.EditField.Value = 'Gij';
            elseif(y_test_pred == 3)
                app.EditField.Value = 'Stop';
            end
        end
        
        function ExtractFeat(app)
            % normalize y
            % remove mean
            app.y_norm= app.y - sum(app.y)/length(app.y);
            % devide by the max abs value
            app.y_norm= app.y_norm / max(abs(app.y_norm));

            % remove silence
            RemoveSilence(app);

            % find pitch
            FindPitch(app);

            % find formants
            FormantsEpo4(app);

            % find Zero Crossing Rate
            x10 = sum(abs(diff(app.y_nosilence>0)))/length(app.y_nosilence);

            % features
            % mean, median, and variance of the pitch:
            x1=mean(app.f0,'omitnan');
            x2=median(app.f0,'omitnan');
            x3=var(app.f0,'omitnan');

            % mean, median, and variance of each formants
            x4=mean(app.frmtns,1,"omitnan");
            x5=median(app.frmtns,1,"omitnan");
            x6=var(app.frmtns,1,"omitnan");

            % mean, median, and variance of F1/F2
            f1f2=app.frmtns(:,1)./app.frmtns(:,2); % F1/F2
            x7=mean(f1f2,'omitnan');
            x8=median(f1f2,'omitnan');
            x9=var(f1f2,'omitnan');

            % putting all the features together: size 1*16
            app.x_test=[x1,x2,x3,x4,x5,x6,x7,x8,x9,x10];
        end
        
        function RemoveSilence(app)
            % find() samples that have amplitudes larger than the threshold
            nonsilent_samples = find(app.y>app.threshold, 1, 'first'):find(app.y>app.threshold, 1, 'last');
            t_start=nonsilent_samples(1);
            t_end=nonsilent_samples(end);
            % only keep the signal between the first and last sample
            app.y_nosilence= app.y( t_start: t_end);
        end
        
        function FindPitch(app)
            app.f0 = pitch(app.y, app.Fs, 'WindowLength', app.L, 'OverlapLength', app.ov, 'Method', 'CEP');
        end
        
        function FormantsEpo4(app)
            %% Formant estimation in a moving window approach

            N = length(app.y);         % length of the audio signal
            win = hann(app.L);         % window
%             t_vec = (app.L:app.ov:N)/Fs;  % time vector for plotting
            preemph = [1 0.63];    % Coefficients of the pre-emphasis filter (Highpass)
            count = 0;
            lng = floor((N-app.L)/(app.ov))+1; % Number of windows within the signal
            app.frmtns = NaN(lng,3,'double');
%             f00 = NaN(lng,1,'double');

            for iter = 1:app.ov:(N - app.L) % Formant estimation for each window of length L
                count = count+1;
                tmp = app.y(iter:iter+app.L-1,:);
                y1 = tmp.*win;
                y1 = filter(1,preemph,y1);
                A = lpc(y1,8);          % Linear predictor coefficients
                rts = roots(A);
                rts = rts(imag(rts)>=0);
                angz = atan2(imag(rts),real(rts));
                [frqs,indices] = sort(angz.*(app.Fs/(2*pi)));
                bw = -1/2*(app.Fs/(2*pi))*log(abs(rts(indices)));
                nn=1; frs = NaN(1,3,'double');
                for kk = 1:length(frqs)
                    if (frqs(kk) > 90 && bw(kk) <400)
                        frs(nn) = frqs(kk);
                        nn = nn+1;
                    end
                end
                app.frmtns(count,:) = frs(1:3);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            PredictVoice(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end