classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Lamp      matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        force = 150;       %force pressed on trigger
        direction = 150;   %direction of wheels
        speed = 0;       %speed of car
        facing = 0;      %direction the car is facing in rad 0 = x
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %quit = 0;
            %while quit == 0
            %    app.facing = app.facing + (app.direction - 150)*pi/180;
            %    app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
            %    app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            %    if app.Lamp.Position(1) == 0 
            %        quit = 1;
            %    else
            %        quit = 0;
            %    end
            %end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if key == 'w' && app.force < 165
                app.force = app.force + 1; 
                app.facing = app.facing + (app.direction - 150)*pi/180;
                app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
                app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            elseif key == 's' && app.force > 135
                app.force = app.force - 1;
                app.facing = app.facing + (app.direction - 150)*pi/180;
                app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
                app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            elseif key == 'd' && app.direction > 100
                app.direction = app.direction - 1;
                app.facing = app.facing + (app.direction - 150)*pi/180;
                app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
                app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            elseif key == 'a' && app.direction < 200
                app.direction = app.direction + 1;
                app.facing = app.facing + (app.direction - 150)*pi/180;
                app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
                app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            elseif key == ' '
                app.force = 150;
                app.direction = 150;
                app.facing = app.facing + (app.direction - 150)*pi/180;
                app.Lamp.Position(1) = app.Lamp.Position(1) + (app.force - 150) * cos(app.facing);
                app.Lamp.Position(2) = app.Lamp.Position(2) + (app.force - 150) * sin(app.facing);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end